classdef winDriveTest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Grid                           matlab.ui.container.GridLayout
        Document                       matlab.ui.container.GridLayout
        axesPanel                      matlab.ui.container.Panel
        toolGrid                       matlab.ui.container.GridLayout
        tool_nRowsGrid                 matlab.ui.container.GridLayout
        tool_resetButton               matlab.ui.control.Image
        tool_nRowsIcon                 matlab.ui.control.Image
        tool_nRows                     matlab.ui.control.Label
        jsBackDoor                     matlab.ui.control.HTML
        tool_SelectedEmision           matlab.ui.control.Label
        tool_Serarator2                matlab.ui.control.Image
        tool_mapPlotSize               matlab.ui.control.Slider
        tool_DensityButton             matlab.ui.control.Image
        tool_ScatterButton             matlab.ui.control.Image
        tool_Serarator1                matlab.ui.control.Image
        tool_ExportButton              matlab.ui.control.Button
        LeftPanelVisibility            matlab.ui.control.Image
        LeftPanel                      matlab.ui.container.Panel
        LeftPanelGrid                  matlab.ui.container.GridLayout
        Tab4_Panel                     matlab.ui.container.GridLayout
        layout_cartesianPlotPanel      matlab.ui.container.Panel
        layout_cartesianPlotGrid       matlab.ui.container.GridLayout
        layout_cartesianPlotSubPanel   matlab.ui.container.Panel
        layout_cartesianPlotSubGrid    matlab.ui.container.GridLayout
        layout_Smoothed_Panel          matlab.ui.container.Panel
        layout_Smoothed_Grid           matlab.ui.container.GridLayout
        layout_Smoothed_Factor         matlab.ui.control.Spinner
        layout_Smoothed_FactorLabel    matlab.ui.control.Label
        layout_Smoothed_Type           matlab.ui.control.DropDown
        layout_Smoothed_TypeLabel      matlab.ui.control.Label
        layout_chPowerSource_smoothed  matlab.ui.control.CheckBox
        layout_chPowerSource_binTable  matlab.ui.control.CheckBox
        layout_chPowerSource_rawTable  matlab.ui.control.CheckBox
        layout_cartesianPlotLabel      matlab.ui.control.Label
        layout_cartesianPlotTitle      matlab.ui.control.Label
        layout_mapPlotPanel            matlab.ui.container.Panel
        layout_mapPlotGrid             matlab.ui.container.GridLayout
        layout_mapPlotLayoutPanel      matlab.ui.container.Panel
        layout_mapPlotLayoutGrid       matlab.ui.container.GridLayout
        layout_Colormap                matlab.ui.control.DropDown
        layout_ColormapLabel           matlab.ui.control.Label
        layout_points_Panel            matlab.ui.container.Panel
        layout_points_Grid             matlab.ui.container.GridLayout
        layout_points_Size             matlab.ui.control.Slider
        layout_points_Color            matlab.ui.control.Button
        layout_points_LineStyle        matlab.ui.control.DropDown
        layout_points_Label            matlab.ui.control.Label
        layout_Car_Panel               matlab.ui.container.Panel
        layout_Car_Grid                matlab.ui.container.GridLayout
        layout_Car_Size                matlab.ui.control.Slider
        layout_Car_Color               matlab.ui.control.Button
        layout_Car_LineStyle           matlab.ui.control.DropDown
        layout_Car_Label               matlab.ui.control.Label
        layout_route_Panel             matlab.ui.container.Panel
        layout_route_Grid              matlab.ui.container.GridLayout
        layout_route_Size              matlab.ui.control.Slider
        layout_route_Color2            matlab.ui.control.Button
        layout_route_Color1            matlab.ui.control.Button
        layout_route_LineStyle         matlab.ui.control.DropDown
        layout_route_Label             matlab.ui.control.Label
        layout_mapPlotLayoutLabel      matlab.ui.control.Label
        layout_mapPlotSourcePanel      matlab.ui.container.ButtonGroup
        layout_mapPlotSource_binData   matlab.ui.control.RadioButton
        layout_mapPlotSource_rawData   matlab.ui.control.RadioButton
        layout_mapPlotSourceLabel      matlab.ui.control.Label
        layout_mapPlotLock             matlab.ui.control.Image
        layout_mapPlotTitle            matlab.ui.control.Label
        Tab3_Panel                     matlab.ui.container.GridLayout
        binTable_Info                  matlab.ui.control.Label
        binTable_UITable               matlab.ui.control.Table
        binTable_Panel                 matlab.ui.container.Panel
        binTable_Grid1                 matlab.ui.container.GridLayout
        binTable_Fcn                   matlab.ui.control.DropDown
        binTable_FcnLabel              matlab.ui.control.Label
        binTable_Factor                matlab.ui.control.Spinner
        binTable_FactorLabel           matlab.ui.control.Label
        binTable_Type                  matlab.ui.control.DropDown
        binTable_TypeLabel             matlab.ui.control.Label
        binTable_Label                 matlab.ui.control.Label
        Tab2_Panel                     matlab.ui.container.GridLayout
        points_Tree                    matlab.ui.container.CheckBoxTree
        points_addImage                matlab.ui.control.Image
        points_addValuePanel           matlab.ui.container.Panel
        points_addValueGrid            matlab.ui.container.GridLayout
        points_addSubtype2Distance     matlab.ui.control.Spinner
        points_addSubtype2DistanceLabel  matlab.ui.control.Label
        points_addSubtype2NPeaks       matlab.ui.control.Spinner
        points_addSubtype2NPeaksLabel  matlab.ui.control.Label
        points_addSubtype2DropDown     matlab.ui.control.DropDown
        points_addSubtype2Label        matlab.ui.control.Label
        points_addSubtype1Distance     matlab.ui.control.NumericEditField
        points_addSubtype1DistanceLabel  matlab.ui.control.Label
        points_addSubtype1TextArea     matlab.ui.control.TextArea
        points_addSubtype1DropDown     matlab.ui.control.DropDown
        points_addSubtype1Label        matlab.ui.control.Label
        points_addTypePanel            matlab.ui.container.ButtonGroup
        points_addFindPeaks            matlab.ui.control.RadioButton
        points_addRFDataHub            matlab.ui.control.RadioButton
        points_TreeLabel               matlab.ui.control.Label
        Tab1_Panel                     matlab.ui.container.GridLayout
        filter_Customization           matlab.ui.control.CheckBox
        filter_Tree                    matlab.ui.container.Tree
        filter_addImageGrid            matlab.ui.container.GridLayout
        filter_addImage                matlab.ui.control.Image
        filter_roiOptions              matlab.ui.container.Tree
        levelFilterNode                matlab.ui.container.TreeNode
        levelFilter_thr                matlab.ui.container.TreeNode
        geoFilterNode                  matlab.ui.container.TreeNode
        geoFilter_Circle               matlab.ui.container.TreeNode
        geoFilter_Rectangle            matlab.ui.container.TreeNode
        geoFilter_Polygon              matlab.ui.container.TreeNode
        filter_TreeLabel               matlab.ui.control.Label
        emissionMetaData               matlab.ui.control.TextArea
        emissionList                   matlab.ui.control.DropDown
        emissionListGrid               matlab.ui.container.GridLayout
        emissionListRefresh            matlab.ui.control.Image
        emissionListLabel              matlab.ui.control.Label
        menu_MainGrid                  matlab.ui.container.GridLayout
        menu_Button4Grid               matlab.ui.container.GridLayout
        menu_Button4Icon               matlab.ui.control.Image
        menu_Button4Label              matlab.ui.control.Label
        menu_Button3Grid               matlab.ui.container.GridLayout
        menu_Button3Icon               matlab.ui.control.Image
        menu_Button3Label              matlab.ui.control.Label
        menu_Button2Grid               matlab.ui.container.GridLayout
        menu_Button2Icon               matlab.ui.control.Image
        menu_Button2Label              matlab.ui.control.Label
        menu_Button1Grid               matlab.ui.container.GridLayout
        menu_Button1Icon               matlab.ui.control.Image
        menu_Button1Label              matlab.ui.control.Label
        menu_Image                     matlab.ui.control.Image
        filter_ContextMenu             matlab.ui.container.ContextMenu
        filter_delButton               matlab.ui.container.Menu
        filter_delAllButton            matlab.ui.container.Menu
        points_ContextMenu             matlab.ui.container.ContextMenu
        points_delButton               matlab.ui.container.Menu
    end

    
    properties (Access = public)
        %-----------------------------------------------------------------%
        CallingApp
        RootFolder

        UIAxes1
        UIAxes2

        restoreView = {[0,1], [0,1]}
        
        SpecInfo
        SpecIndex
        emissionTable

        specTable                                                           % General table (instantaneous channel power)
        filtTable                                                           % Filtered specData
        binTable                                                            % Summarized filtTable (Data Binning Criterium)

        filterTable = table({}, {}, struct('handle', {}, 'specification', {}), true(0, 1), ...
                            'VariableNames', {'type', 'subtype', 'roi', 'enable'})
        
        pointsTable = table({}, struct('source', {}, 'idx', {}, 'Coordinates', {}), true(0, 1),...
                            'VariableNames', {'type', 'value', 'visible'})

        hCar
        hTimeStamp
        hTimeStampLabel

        timerObj_startup
    end

    
    methods (Access = private)
        %-----------------------------------------------------------------%
        function startup_timerCreation(app)
            
            % A criação desse timer tem como objetivo garantir uma renderização 
            % mais rápida dos componentes principais da GUI, possibilitando a 
            % visualização da sua tela inicialpelo usuário. Trata-se de aspecto 
            % essencial quando o app é compilado como webapp.

            app.timerObj_startup = timer("ExecutionMode", "fixedSpacing", ...
                                         "StartDelay",    1.5,            ...
                                         "Period",        .1,             ...
                                         "TimerFcn",      @(~,~)app.startup_timerFcn);
            start(app.timerObj_startup)
        end


        %-----------------------------------------------------------------%
        function startup_timerFcn(app)
            if ccTools.fcn.UIFigureRenderStatus(app.UIFigure)
                stop(app.timerObj_startup)
                
                % Customiza as aspectos estéticos de alguns dos componentes da GUI 
                % (diretamente em JS).
                jsBackDoor_Customizations(app)
                drawnow

                layout_LeftPanelSelectedTabControl(app, struct('Source', app.menu_Button1Icon))
                points_addTypePanelSelectionChanged(app)
    
                startup_AxesCreation(app)
                startup_GeneralMetaData(app)
                startup_General(app)
    
                set(app.CallingApp.play_Lock, 'Enable', 0, 'ImageSource', 'LT_lockClose.png')
                app.CallingApp.play_Tree.Enable = 0;
                drawnow

                % WARNING MESSAGES
                warning('off', 'MATLAB:ui:javaframe:PropertyToBeRemoved')
                warning('off', 'MATLAB:subscripting:noSubscriptsSpecified')
                warning('off', 'MATLAB:structOnObject')
                warning('off', 'MATLAB:class:DestructorError')
                warning('off', 'MATLAB:modes:mode:InvalidPropertySet')
                warning('off', 'MATLAB:table:RowsAddedExistingVars')
    
                % Define tamanho mínimo da GUI (aplicável apenas para a versão 
                % desktop do app.
                ccTools.compCustomization(app.UIFigure, 'windowMinSize', class.Constants.windowMinSize);
                drawnow
            end
        end


        %-----------------------------------------------------------------%
        function jsBackDoor_Initialization(app)
            app.jsBackDoor.HTMLSource = ccTools.fcn.jsBackDoorHTMLSource();
        end


        %-------------------------------------------------------------------------%
        function jsBackDoor_Customizations(app)
            sendEventToHTMLSource(app.jsBackDoor, 'htmlClassCustomization', struct('className',        '.mw-theme-light',                                                   ...
                                                                                   'classAttributes', ['--mw-backgroundColor-dataWidget-selected: rgb(180 222 255 / 45%); ' ...
                                                                                                       '--mw-backgroundColor-selected: rgb(180 222 255 / 45%); '            ...
                                                                                                       '--mw-backgroundColor-selectedFocus: rgb(180 222 255 / 45%);']));

            sendEventToHTMLSource(app.jsBackDoor, 'htmlClassCustomization', struct('className',        '.mw-default-header-cell', ...
                                                                                   'classAttributes',  'font-size: 10px; white-space: pre-wrap; margin-bottom: 5px;'));
        end


        %-----------------------------------------------------------------%
        function startup_AxesCreation(app)
            app.axesPanel.AutoResizeChildren = 'off';
            app.UIAxes1 = plotFcn.axesDraw.AxesCreation(app, 'Geographic', app.axesPanel);
            set(app.UIAxes1, Units = "normalized", Position = [0,0,1,1])
            geolimits(app.UIAxes1, 'auto')

            app.UIAxes2 = plotFcn.axesDraw.AxesCreation(app, 'Cartesian',  app.Document);
            app.UIAxes2.Layout.Row = 2;
            app.UIAxes2.Layout.Column = 1;

            app.UIAxes2.XAxis.Exponent = 0;
            xlabel(app.UIAxes2, 'Varredura')
            ylabel(app.UIAxes2, 'Potência canal (dBm)')            

            colormap(app.UIAxes1, app.layout_Colormap.Value)
            app.UIAxes1.Colormap(1,:) = [0,0,0];

            % drawnow
        end


        %-----------------------------------------------------------------%
        function startup_GeneralMetaData(app)
            metaData = '';
            for ii = 1:height(app.SpecInfo.RelatedFiles)
                ObservationTime = sprintf('%s - %s', datestr(app.SpecInfo.RelatedFiles.BeginTime(ii), 'dd/mm/yyyy HH:MM:SS'), ...
                                                     datestr(app.SpecInfo.RelatedFiles.EndTime(ii),   'dd/mm/yyyy HH:MM:SS'));

                metaData = sprintf(['%s• %s\n' ...
                                    '  %s\n' ...
                                    '  %.0f varreduras, %.3f seg (tempo de revisita médio)\n\n'], ...
                                    metaData,                              ...
                                    app.SpecInfo.RelatedFiles.File{ii},    ...
                                    ObservationTime,                       ...
                                    app.SpecInfo.RelatedFiles.nSweeps(ii), ...
                                    app.SpecInfo.RelatedFiles.RevisitTime(ii));
            end

            app.emissionMetaData.Value = sprintf(['Faixa: %.3f - %.3f MHz\n\n'      ...
                                           'Pontos por traço: %.0f\n'               ...
                                           'Unidade: %s\n'                          ...
                                           'Resolução: %.3f kHz\n\n%s'],            ...
                                           app.SpecInfo.MetaData.FreqStart / 1e+6,  ...
                                           app.SpecInfo.MetaData.FreqStop  / 1e+6,  ...
                                           app.SpecInfo.MetaData.DataPoints,        ...
                                           app.SpecInfo.MetaData.LevelUnit,         ...
                                           app.SpecInfo.MetaData.Resolution / 1e+3, ...
                                           metaData);
        end
        

        %-----------------------------------------------------------------%
        function startup_General(app)
            startup_EmissionsList(app)
            emission_ValueChanged(app, struct('Source', app.emissionList, 'PreviousValue', app.emissionList.Value))

            expand(app.filter_roiOptions, 'all')
        end


        %-----------------------------------------------------------------%
        function startup_EmissionsList(app)
            app.emissionTable = app.SpecInfo.UserData.Emissions;

            if isempty(app.emissionTable)
                msg = 'Tabela de emissões está vazia!';
                layoutFcn.modalWindow(app.UIFigure, 'ccTools.MessageBox', msg);
                closeFcn(app)

            else
                app.emissionList.Items = {};
                for ii = 1:height(app.emissionTable)
                    app.emissionList.Items{end+1} = sprintf('%d: %.3f MHz ⌂ %.3f kHz',        ...
                                                              ii,                              ...
                                                              app.emissionTable.Frequency(ii), ...
                                                              app.emissionTable.BW(ii));
                end
    
                app.emissionList.Value = app.emissionList.Items{app.SpecIndex(2)};
            end
        end


        %-----------------------------------------------------------------%
        function checkIndex(app)
            idx1 = find(cellfun(@(x) isequal(x, app.SpecInfo.RelatedFiles.uuid), arrayfun(@(x) x.RelatedFiles.uuid, app.CallingApp.specData, 'UniformOutput', false)), 1);
            idx2 = find(strcmp(app.emissionList.Items, app.emissionList.Value), 1);

            if  ~isequal(app.emissionTable(:,1:3), app.CallingApp.specData(idx1).UserData.Emissions(:,1:3))
                error('Lista de emissões foi alterada, o que demanda a sua atualização.')
            end

            app.SpecIndex = [idx1, idx2];
        end


        %-----------------------------------------------------------------%
        function emission_MetaDataInfo(app)
            app.tool_SelectedEmision.Text = sprintf('%s\nID %d: %.3f - %.3f MHz @ %s', app.SpecInfo.Receiver,                 ...
                                                                                       app.SpecInfo.RelatedFiles.ID(1),       ...
                                                                                       app.SpecInfo.MetaData.FreqStart/1e+6,  ...
                                                                                       app.SpecInfo.MetaData.FreqStop /1e+6,  ...
                                                                                       extractAfter(app.emissionList.Value, ': '));
        end


        %-----------------------------------------------------------------%
        function filter_TreeBuilding(app)
            if ~isempty(app.filter_Tree.Children)
                delete(app.filter_Tree.Children)
            end

            if ~isempty(app.filterTable)
                for ii = 1:height(app.filterTable)
                    uitreenode(app.filter_Tree, 'Text', app.filterTable.subtype{ii}, 'NodeData', ii, 'ContextMenu', app.filter_ContextMenu);
                end

                set(app.filter_Tree, 'SelectedNodes', app.filter_Tree.Children(end), 'Enable', 1)
                filter_TreeSelectionChanged(app)

                set(app.filter_ContextMenu.Children, Enable=1)
            else
                app.filter_Tree.Enable = 1;
                set(app.filter_ContextMenu.Children, Enable=0)
            end

            if isempty(find(app.filterTable.subtype == "Threshold", 1))
                s = uistyle("FontColor", [0,0,0]);                    
                app.levelFilter_thr.NodeData = 1;
            else
                s = uistyle("FontColor", [.8,.8,.8]);
                app.levelFilter_thr.NodeData = [];
            end
            addStyle(app.filter_roiOptions, s, 'node', app.levelFilter_thr)
        end


        %-----------------------------------------------------------------%
        function filter_updateInfo(app)
            d = uiprogressdlg(app.UIFigure, "Indeterminate", "on", "Message", "Em andamento...", "Interpreter", "html");

            try
                checkIndex(app)
    
                binning_geoTables(app)
                binning_UITable(app)
                plot_MainControl(app)

                filter_updateProp(app)                

            catch ME
                layoutFcn.modalWindow(app.UIFigure, 'ccTools.MessageBox', ME.message);
            end

            delete(d)
        end


        %-----------------------------------------------------------------%
        function filter_updateProp(app)
            idx1 = app.SpecIndex(1);
            idx2 = app.SpecIndex(2);

            if app.filter_Customization.Value
                app.CallingApp.specData(idx1).UserData.Emissions.UserData{idx2} = ParametersCreation(app);
            else
                app.CallingApp.specData(idx1).UserData.Emissions.UserData{idx2} = [];
            end
        end


        %-----------------------------------------------------------------%
        function specification = filter_roiSpecification(app, subtype, hROI)
            switch subtype
                case 'Threshold'; specification = struct('Position',  hROI.Position);
                case 'Circle';    specification = struct('Center',    hROI.Center,   'Radius',        hROI.Radius);
                case 'Rectangle'; specification = struct('Position',  hROI.Position, 'RotationAngle', hROI.RotationAngle);
                case 'Polygon';   specification = struct('Position',  hROI.Position);
            end
        end


        %-----------------------------------------------------------------%
        function binning_Layout(app)
            switch app.binTable_Type.Value
                case 'Grid retangular - Comprimento bin'
                    set(app.binTable_FactorLabel, 'Text', 'Comprimento <i>bin</i> (metros):')
                    set(app.binTable_Factor, 'Limits', [50, 1500], 'Value', 100, 'Step', 50)

                case 'Grid retangular - Coordenadas geográficas truncadas'
                    set(app.binTable_FactorLabel, 'Text', 'Casas decimais:')
                    set(app.binTable_Factor, 'Limits', [1, 10], 'Value', 6, 'Step', 1)
            end
        end
       

        %-----------------------------------------------------------------%
        function binning_specTableCreation(app)
            timestamp = app.SpecInfo.Data{1}';
            
            Lat  = [];
            Long = [];
            for ii = 1:height(app.SpecInfo.RelatedFiles)
                Lat  = [Lat;  app.SpecInfo.RelatedFiles.GPS{ii}.Matrix(:,1)];
                Long = [Long; app.SpecInfo.RelatedFiles.GPS{ii}.Matrix(:,2)];
            end
            
            chPower = binning_chPower(app);
            app.specTable = table(timestamp, Lat, Long, chPower);
        end
        
        
        %-----------------------------------------------------------------%
        function binning_geoTables(app)
            binning_filtTable(app)
            binning_binTable(app)
        end
        

        %-----------------------------------------------------------------%
        function binning_filtTable(app)
            idy  = ones(height(app.specTable), 1, 'logical');

            idx1 = find(app.filterTable.type == "Level");
            idx2 = find(app.filterTable.type == "Geographic ROI");

            if ~isempty(idx1) & ~isempty(idx2)
                for ii = idx1'
                    idy1 = app.specTable.chPower >= app.filterTable.roi(ii).handle.Position(3);
                end

                idy2 = zeros(height(app.specTable), 1, 'logical');
                for ii = idx2'
                    idy2 = or(idy2, inROI(app.filterTable.roi(ii).handle, app.specTable.Lat, app.specTable.Long));
                end

                idy = and(idy1, idy2);

            elseif ~isempty(idx1)
                for ii = idx1'
                    idy1 = app.specTable.chPower >= app.filterTable.roi(ii).handle.Position(3);
                end

                idy = idy1;

            elseif ~isempty(idx2)
                idy2 = zeros(height(app.specTable), 1, 'logical');
                for ii = idx2'
                    idy2 = or(idy2, inROI(app.filterTable.roi(ii).handle, app.specTable.Lat, app.specTable.Long));
                end

                idy = idy2;                
            end
            
            if ~sum(idy)
                layoutFcn.modalWindow(app.UIFigure, 'ccTools.MessageBox', 'Não foi obtida nenhuma amostra ao aplicar o filtro. Por essa razão, esse filtro será excluído automaticamente.');
                app.filter_Tree.SelectedNodes = app.filter_Tree.Children(end);
                filter_delFilter(app, struct('Source', app.filter_delButton))

                return
            end

            app.specTable.filtered = idy;
            app.specTable.PK1      = int32(1:height(app.specTable))';

            app.filtTable = app.specTable(idy,2:4);
            [app.filtTable.LatEq, app.filtTable.LongEq] = grn2eqa(app.filtTable.Lat, app.filtTable.Long);

            app.filtTable.FK1 = find(idy);
            app.filtTable.FK2 = -1*ones(height(app.filtTable), 1, 'int32');

            NN = sum(idy);
            MM = height(app.specTable);
            app.tool_nRows.Text = sprintf('%d de %d varreduras\n%.1f %%', NN, MM, (NN/MM)*100);

            if ~isempty(idx1)
                app.filterTable.roi(idx1).handle.Position(2) = height(app.filtTable);
            end
        end


        %-----------------------------------------------------------------%
        function binning_binTable(app)
            switch app.binTable_Type.Value
                case 'Grid retangular - Comprimento bin'
                    [binLat, binLong, binCount] = hista(app.filtTable.Lat, app.filtTable.Long, (app.binTable_Factor.Value/1000)^2);            
                    [binLatEq, binLongEq] = grn2eqa(binLat, binLong);
                    PK2 = int32(1:numel(binLat))';
                    
                    dist = pdist2([app.filtTable.LongEq, app.filtTable.LatEq], [binLongEq, binLatEq]);            
                    [~, app.filtTable.FK2] = min(dist, [], 2);
                    binPower = binning_binPower(app);
        
                    app.binTable = table(binLat, binLong, binPower, binCount, PK2);

                case 'Grid retangular - Coordenadas geográficas truncadas'
                    lat_String = strsplit(sprintf(sprintf('%%.%.0ff\n', app.binTable_Factor.Value), app.filtTable.Lat),  '\n')';
                    lon_String = strsplit(sprintf(sprintf('%%.%.0ff\n', app.binTable_Factor.Value), app.filtTable.Long), '\n')';
                    latlong    = string(lat_String(1:end-1)) + ", " + string(lon_String(1:end-1));
        
                    [latlong_String, latlong_Index, app.filtTable.FK2] = unique(latlong, 'stable');
                    
                    PK2      = int32(1:numel(latlong_String))';
                    binLat   = round(app.filtTable.Lat(latlong_Index),  app.binTable_Factor.Value);
                    binLong  = round(app.filtTable.Long(latlong_Index), app.binTable_Factor.Value);
                    binCount = accumarray(app.filtTable.FK2, 1);
                    binPower = binning_binPower(app);
        
                    app.binTable = table(binLat, binLong, binPower, binCount, PK2);
            end
        end


        %-----------------------------------------------------------------%
        function chPower = binning_chPower(app)
            idx = find(strcmp(app.emissionList.Items, app.emissionList.Value), 1);

            FreqCenter = app.SpecInfo.UserData.Emissions.Frequency(idx) * 1e+6;
            BW         = app.SpecInfo.UserData.Emissions.BW(idx)        * 1e+3;

            chPower = fcn.chPower(app.SpecInfo, [FreqCenter-BW/2, FreqCenter+BW/2]);
        end


        %-----------------------------------------------------------------%
        function binPower = binning_binPower(app)
            switch app.binTable_Fcn.Value
                case 'min';    binFcn = @min;
                case 'mean';   binFcn = @(x) pow2db(  mean(db2pow(x)));
                case 'median'; binFcn = @(x) pow2db(median(db2pow(x)));
                case 'rms';    binFcn = @(x) pow2db(   rms(db2pow(x)));
                case 'max';    binFcn = @max;
            end
            binPower = splitapply(binFcn, app.filtTable.chPower, app.filtTable.FK2);
        end


        %-----------------------------------------------------------------%
        function binning_UITable(app)
            binLat_String  = cellfun(@(x) sprintf('%.6f', x), num2cell(app.binTable.binLat),  'UniformOutput', false);
            binLong_String = cellfun(@(x) sprintf('%.6f', x), num2cell(app.binTable.binLong), 'UniformOutput', false);
            app.binTable_UITable.Data = table(app.binTable{:,5}, binLat_String, binLong_String, app.binTable{:,4});

            s = uistyle("HorizontalAlignment", 'right');
            addStyle(app.binTable_UITable, s, "column", [2,3])            

            % Identificar as distâncias entre pontos adjacentes (ajustados
            % no grid definido pelo número das casas decimais da latitude e
            % longitude)
            locIndex = app.filtTable.FK2(1);
            for ii = 1:height(app.filtTable)
                if locIndex(end) ~= app.filtTable.FK2(ii)
                    locIndex(end+1,1) = app.filtTable.FK2(ii);
                end
            end

            [binCount_min, binCount_max] = bounds(app.binTable.binCount);

            if numel(locIndex) >= 2
                ptDist = deg2km(distance('gc', [app.binTable.binLat(locIndex(1:end-1)), app.binTable.binLong(locIndex(1:end-1))], ...
                                               [app.binTable.binLat(locIndex(2:end)),   app.binTable.binLong(locIndex(2:end))]));
                [ptDist_min, ptDist_max] = bounds(ptDist*1000);
                ptDist_mean = mean(ptDist)*1000; 
    
                % Sumário:            
                auxString0 = sprintf('%.0f pontos', height(app.binTable));
                auxString1 = sprintf('%.0f |––––––– %.0f –––––––| %.0f metros', ptDist_min, ptDist_mean, ptDist_max);
                auxString2 = sprintf('%.0f |–––––––| %.0f varreduras/ponto',    binCount_min, binCount_max);
    
                app.binTable_Info.Text = sprintf('%s\n%s\n%.0f |–––––––%s| %.0f varreduras/ponto', auxString0, auxString1, binCount_min, repmat('–', 1, numel(auxString1)-numel(auxString2)+1), binCount_max);
            else
                app.binTable_Info.Text = '';
            end
        end


        %-----------------------------------------------------------------%
        function points_TreeBuilding(app)
            if ~isempty(app.points_Tree.Children)
                delete(app.points_Tree.Children)
            end

            checkedTreeNodes = [];
            for ii = 1:height(app.pointsTable)
                treeNode = uitreenode(app.points_Tree, 'Text', sprintf('%s: %s', app.pointsTable.type{ii}, strjoin(string(app.pointsTable.value(ii).idx), ',')), ...
                                                       'NodeData', ii, 'ContextMenu', app.points_ContextMenu);
                if app.pointsTable.visible(ii)
                    checkedTreeNodes = [checkedTreeNodes, treeNode];
                end
            end
            app.points_Tree.CheckedNodes = checkedTreeNodes;

            if app.filter_Customization.Value
                idx1 = app.SpecIndex(1);
                idx2 = app.SpecIndex(2);

                app.CallingApp.specData(idx1).UserData.Emissions.UserData{idx2}.pointsTable = app.pointsTable;
            end
        end


        %-----------------------------------------------------------------%
        function points_TableCheck(app, newRow)
            status = true;
            for ii = 1:height(app.pointsTable)
                if isequal(newRow(1), app.pointsTable{ii,1}) && ...
                   isequal(newRow{2}, app.pointsTable{ii,2})
                    status = false;
                    break
                end
            end

            if status
                app.pointsTable(end+1,:) = newRow;
            else
                error('Registro já incluído!')
            end
        end


        %-----------------------------------------------------------------%
        function [idx, Coordinates] = points_FindPeaks(app, Source, NPeaks, MinDistance)
            idx = [];
            Coordinates = [];

            switch Source
                case 'Dados brutos';                     sourceData = get(findobj(app.UIAxes2.Children, 'Tag', 'rawTable'),     'YData');
                case 'Dados processados (Data Binning)'; sourceData = get(findobj(app.UIAxes2.Children, 'Tag', 'binTable'),     'YData');
                case 'Curva suave';                      sourceData = get(findobj(app.UIAxes2.Children, 'Tag', 'SmoothedData'), 'YData');
            end

            [~, pksIndex] = findpeaks(sourceData, 'SortStr', 'descend');

            if ~isempty(pksIndex)
                idx = pksIndex(1);

                for ii = 2:numel(pksIndex)
                    if numel(idx) >= NPeaks
                        break
                    end

                    switch Source
                        case {'Dados brutos', 'Curva suave'}
                            refCoordinates = app.filtTable{idx, 1:2};
                            Coordinates    = app.filtTable{pksIndex(ii), 1:2};    
                        
                        case 'Dados processados (Data Binning)'
                            refCoordinates = app.binTable{app.filtTable.FK2(arrayfun(@(x) find(app.filtTable.FK1 == x, 1), idx)), 1:2};
                            Coordinates    = app.binTable{app.filtTable.FK2(arrayfun(@(x) find(app.filtTable.FK1 == x, 1), pksIndex(ii))), 1:2};
                    end
    
                    tempDistance = deg2km(distance(refCoordinates, Coordinates(end,:))); % em km
                    if all(tempDistance >= MinDistance)
                        idx(end+1) = pksIndex(ii);
                    end
                end

                switch Source
                    case {'Dados brutos', 'Curva suave'};    Coordinates = app.filtTable{idx, 1:2};    
                    case 'Dados processados (Data Binning)'; Coordinates = app.binTable{app.filtTable.FK2(arrayfun(@(x) find(app.filtTable.FK1 == x, 1), idx)), 1:2};
                end
            end
        end


        %-----------------------------------------------------------------%
        function plot_MainControl(app)
            % app.UIAxes2
            plot_ChannelPower(app)
            
            % app.UIAxes1
            plotFcn.axesDraw.geographicAxes_type1(app.UIAxes1, ParametersCreation(app), 'auxApp.winDriveTest')
            plot_Car(app)

            drawnow
        end


        %-----------------------------------------------------------------%
        function plot_ChannelPower(app)
            delete(findobj(app.UIAxes2.Children, '-not', 'Tag', 'ROI'))

            chColor         = app.CallingApp.General.Plot.ClearWrite.EdgeColor;
            binColor        = app.CallingApp.General.Plot.MaxHold.EdgeColor;
            smoothedColor   = app.CallingApp.General.Plot.MinHold.EdgeColor;

            [~, idx]        = min(abs(app.filtTable.FK1-app.CallingApp.timeIndex));

            [minY, maxY]    = bounds(app.specTable.chPower);
            set(app.UIAxes2, 'XLim', [1, height(app.filtTable)+.001], 'YLim', [minY, maxY+.001])
            app.restoreView = {app.UIAxes2.XLim, app.UIAxes2.YLim};
                                
            smoothedTable         = app.filtTable;
            smoothedTable.chPower = smoothdata(app.filtTable.chPower, app.layout_Smoothed_Type.Value, 'SmoothingFactor', app.layout_Smoothed_Factor.Value);

            chPowerLine           = plot(app.UIAxes2, app.filtTable.chPower,                    'Marker', app.layout_points_LineStyle.Value, 'MarkerSize', 8, 'MarkerIndices', [], 'MarkerFaceColor', chColor,       'MarkerEdgeColor', chColor,       'Color', chColor,       'Visible', app.layout_chPowerSource_rawTable.Value, 'Tag', 'rawTable');
            binPowerLine          = plot(app.UIAxes2, app.binTable.binPower(app.filtTable.FK2), 'Marker', app.layout_points_LineStyle.Value, 'MarkerSize', 8, 'MarkerIndices', [], 'MarkerFaceColor', binColor,      'MarkerEdgeColor', binColor,      'Color', binColor,      'Visible', app.layout_chPowerSource_binTable.Value, 'Tag', 'binTable');
            smoothedLine          = plot(app.UIAxes2, smoothedTable.chPower,                    'Marker', app.layout_points_LineStyle.Value, 'MarkerSize', 8, 'MarkerIndices', [], 'MarkerFaceColor', smoothedColor, 'MarkerEdgeColor', smoothedColor, 'Color', smoothedColor, 'Visible', app.layout_chPowerSource_smoothed.Value, 'Tag', 'SmoothedData');

            app.hTimeStamp        = plot(app.UIAxes2, [idx idx], [minY maxY], 'Color', 'red', 'Marker', '.', 'MarkerSize', 12, 'PickableParts', 'none');
            app.hTimeStampLabel   = text(app.UIAxes2, idx, app.UIAxes2.YLim(1)+.94*diff(app.UIAxes2.YLim), sprintf('  %.0f dBm', app.filtTable.chPower(idx)), 'FontSize', 10, 'FontWeight', 'bold', 'Color', 'red', 'FontSmoothing', 'on', 'PickableParts', 'none');

            legend(app.UIAxes2, {'Dados brutos', 'Data Binning', 'Curva suave'}, FontSize=7, Color=[.98,.98,.98], EdgeColor=[.98,.98,.98], Location='south', Box='on', NumColumns=3, PickableParts='none')

            plotFcn.axesDataTipTemplate.execute('SweepID+ChannelPower', chPowerLine,  [], app.SpecInfo.MetaData.LevelUnit)
            plotFcn.axesDataTipTemplate.execute('SweepID+ChannelPower', binPowerLine, [], app.SpecInfo.MetaData.LevelUnit)
            plotFcn.axesDataTipTemplate.execute('SweepID+ChannelPower', smoothedLine, [], app.SpecInfo.MetaData.LevelUnit)
        end


        %-----------------------------------------------------------------%
        function plot_Car(app)
            delete(app.hCar)

            idx = round(app.hTimeStamp.XData(1));
            app.hCar = geoscatter(app.UIAxes1, app.filtTable.Lat(idx), app.filtTable.Long(idx), 'filled',                                              ...
                                  'Marker', app.layout_Car_LineStyle.Value, 'MarkerFaceColor', app.layout_Car_Color.BackgroundColor, 'MarkerEdgeColor', 'black', ...
                                  'SizeData', 10*app.layout_Car_Size.Value, 'PickableParts', 'none', 'Tag', 'Car');
        end


        %-----------------------------------------------------------------%
        function plot_ROICallback(app, src, event)
            switch(event.EventName)
                case 'MovingROI'
                    plotFcn.axesInteraction.DisableDefaultInteractions([app.UIAxes1, app.UIAxes2])
                    
                case 'ROIMoved'
                    plotFcn.axesInteraction.EnableDefaultInteractions([app.UIAxes1, app.UIAxes2])
                    filter_updateInfo(app)
            end            
        end


        %-----------------------------------------------------------------%
        function Parameters = ParametersCreation(app)

            for ii = 1:height(app.filterTable)
                app.filterTable.roi(ii).specification = filter_roiSpecification(app, app.filterTable.subtype{ii}, app.filterTable.roi(ii).handle);
            end

            Parameters = struct('Source',                checkSource(app, 'Read'),                ...
                                'specTable',             app.specTable,                           ...
                                'filtTable',             app.filtTable,                           ...
                                'binTable',              app.binTable,                            ...
                                'filterTable',           app.filterTable,                         ...
                                'pointsTable',           app.pointsTable,                         ...
                                'binning_Type',          app.binTable_Type.Value,                 ...
                                'binning_Value',         app.binTable_Factor.Value,               ...
                                'binning_Fcn',           app.binTable_Fcn.Value,                  ...
                                'route_LineStyle',       app.layout_route_LineStyle.Value,        ...
                                'route_OutColor',        app.layout_route_Color1.BackgroundColor, ...
                                'route_InColor',         app.layout_route_Color2.BackgroundColor, ...
                                'route_MarkerSize',      app.layout_route_Size.Value,             ...
                                'distortion_Size',       app.tool_mapPlotSize.Value,              ...
                                'distortion_Visibility', checkVisibility(app, 'Distortion'),      ...
                                'density_Size',          app.tool_mapPlotSize.Value,              ...
                                'density_Visibility',    checkVisibility(app, 'Density'),         ...
                                'points_Marker',         app.layout_points_LineStyle.Value,       ...
                                'points_Color',          app.layout_points_Color.BackgroundColor, ...
                                'points_Size',           app.layout_points_Size.Value,            ...
                                'Colormap',              app.layout_Colormap.Value,               ...
                                'Basemap',               app.UIAxes1.Basemap);
        end


        %-----------------------------------------------------------------%
        function Source = checkSource(app, Type)
            switch Type
                case 'Read'
                    switch app.layout_mapPlotSourcePanel.SelectedObject.Text
                        case 'Dados brutos'; Source = 'RawData';
                        otherwise;           Source = 'BinData';
                    end

                case 'Write'
                    Source = '';

                    idx1 = app.SpecIndex(1);
                    idx2 = app.SpecIndex(2);

                    switch app.CallingApp.specData(idx1).UserData.Emissions.UserData{idx2}.Source
                        case 'RawData'; app.layout_mapPlotSource_rawData.Value = 1;
                        otherwise;      app.layout_mapPlotSource_binData.Value = 1;
                    end
            end
        end


        %-----------------------------------------------------------------%
        function Visibility = checkVisibility(app, plotType)
            Visibility = true;

            switch plotType
                case 'Distortion'
                    if ~contains(app.tool_ScatterButton.ImageSource, 'DriveTest_Scatter_ON.png', 'IgnoreCase', true)
                        Visibility = false;
                    end

                case 'Density'
                    if ~contains(app.tool_DensityButton.ImageSource, 'DriveTest_Density_ON.png', 'IgnoreCase', true)
                        Visibility = false;
                    end
            end
        end
    end
    
    
    methods (Access = public)
        %-----------------------------------------------------------------%
        function TimeStampUpdatePosition(app, timeIndex)
            idx = find(app.filtTable.FK1 == timeIndex, 1);

            if ~isempty(idx)
                app.hTimeStamp.XData(1:2)       = idx;
                app.hTimeStampLabel.Position(1) = idx;
                app.hTimeStampLabel.String      = sprintf('  %.0f dBm', app.filtTable.chPower(idx));
                set(app.hCar, 'LatitudeData', app.filtTable.Lat(idx), 'LongitudeData', app.filtTable.Long(idx))
            else
                set(app.hCar, 'LatitudeData', NaN, 'LongitudeData', NaN)
            end
            drawnow
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, idx1, idx2)
            
            app.CallingApp = mainapp;
            app.RootFolder = app.CallingApp.RootFolder;
            app.SpecInfo   = app.CallingApp.specData(idx1);
            app.SpecIndex  = [idx1, idx2];

            fcn.winPosition(app)
            app.UIFigure.WindowState = 'maximized';            

            jsBackDoor_Initialization(app)
            startup_timerCreation(app)
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            set(app.CallingApp.play_Lock, 'Enable', 1, 'ImageSource', 'LT_lockOpen.png')
            app.CallingApp.play_Tree.Enable = 1;

            try
                checkIndex(app)
                app.CallingApp.auxiliarWinFunction(app, 'emissionTreeUpdate', app.SpecIndex)
            catch
            end

            app.CallingApp.auxiliarWin2 = [];
            delete(app)
            
        end

        % Image clicked function: tool_resetButton
        function resetFcn(app, event)
            
            msg = ['<font style="font-size:12;">Essa funcionalidade possibilita reinicializar alguns dos parâmetros do <i>app</i>, ' ...
                   'como a INTERAÇÃO com os plots e a exclusão de FILTROS.</font>'];
            selection = uiconfirm(app.UIFigure, msg, '', 'Options', {'Interação', 'Filtros', 'Cancelar'}, 'DefaultOption', 1, 'Icon', 'question', 'Interpreter', 'html');

            if strcmp(selection, 'Cancelar')
                return
            end

            switch selection
                case 'Interação'
                    plotFcn.axesInteraction.DisableDefaultInteractions([app.UIAxes1, app.UIAxes2])
                    pause(.1)

                    plotFcn.axesInteraction.EnableDefaultInteractions([app.UIAxes1, app.UIAxes2])

                    filter_TreeSelectionChanged(app)

                case 'Filtros'
                    filter_delFilter(app, struct('Source', app.filter_delAllButton))
            end

        end

        % Image clicked function: menu_Button1Icon, menu_Button2Icon, 
        % ...and 2 other components
        function layout_LeftPanelSelectedTabControl(app, event)
            
            idx = str2double(event.Source.Tag);
            NN  = numel(app.menu_MainGrid.ColumnWidth);

            for ii = 1:NN
                switch ii
                    case idx
                        eval(sprintf('app.menu_Button%dGrid.ColumnWidth{2} = "1x";', ii))
                    otherwise
                        eval(sprintf('app.menu_Button%dGrid.ColumnWidth{2} = 0;',    ii))
                end
            end

            columnWidth      = repmat({22}, 1, NN);
            columnWidth(idx) = {'1x'};

            rowHeight        = repmat({0}, 1, NN);
            rowHeight(idx)   = {'1x'};

            app.menu_MainGrid.ColumnWidth      = columnWidth;
            app.menu_Image.Layout.Column       = idx;
            app.LeftPanelGrid.RowHeight(2:end) = rowHeight;

        end

        % Image clicked function: LeftPanelVisibility
        function layout_LeftPanelVisibilityControl(app, event)
            
            if app.Grid.ColumnWidth{1} == 300
                app.LeftPanelVisibility.ImageSource = 'LT_HideRight.png';
                app.Grid.ColumnWidth{1} = 0;
            else
                app.LeftPanelVisibility.ImageSource = 'LT_HideLeft.png';
                app.Grid.ColumnWidth{1} = 300;
            end

        end

        % Button pushed function: tool_ExportButton
        function tool_ExportFileButtonPushed(app, event)
            
            defaultFilename = class.Constants.DefaultFileName(app.CallingApp.menu_userPath.Value, 'Drive-test', app.CallingApp.report_Issue.Value);

            [fileName, filePath, fileIndex] = uiputfile({'*.xlsx', 'appAnalise (*.xlsx)'}, '', defaultFilename);
            figure(app.UIFigure)
    
            if fileIndex
                d = uiprogressdlg(app.UIFigure, "Indeterminate", "on", "Message", "Em andamento...", "Interpreter", "html");

                try
                    FullFileName = fullfile(filePath, fileName);                    
        
                    writetable(app.specTable,              FullFileName, 'FileType', 'spreadsheet', 'WriteMode', 'replacefile',    'Sheet', 'specTable')
                    writetable(app.filtTable(:,[1:3,6:7]), FullFileName, 'FileType', 'spreadsheet', 'WriteMode', 'overwritesheet', 'Sheet', 'filtTable')
                    writetable(app.binTable,               FullFileName, 'FileType', 'spreadsheet', 'WriteMode', 'overwritesheet', 'Sheet', 'binTable')

                    kmlwriteline(replace(FullFileName, '.xlsx', '.kml'), app.specTable.Lat, app.specTable.Long, 'Color', 'red', 'LineWidth', 3);

                catch ME
                    layoutFcn.modalWindow(app.UIFigure, 'ccTools.MessageBox', getReport(ME));
                end

                delete(d)
            end

        end

        % Image clicked function: emissionListRefresh
        function emission_RefreshListImageClicked(app, event)
            
            preSelection = extractAfter(app.emissionList.Value, ': ');
            
            try
                % O try/catch aqui apenas evita a situação em que as emissões 
                % foram excluídas no modo PLAYBACK, então não há emissões para 
                % analisar... nesse caso, o app auxiliar será fechado.
                startup_EmissionsList(app)
    
                idx = find(contains(app.emissionList.Items, preSelection), 1);
                if ~isempty(idx)
                    app.emissionList.Value = app.emissionList.Items(idx);
                end
                emission_ValueChanged(app, struct('Source', app.emissionList, 'PreviousValue', app.emissionList.Value))

            catch
            end

        end

        % Value changed function: emissionList
        function emission_ValueChanged(app, event)

            try
                checkIndex(app)

                emission_MetaDataInfo(app)
                binning_specTableCreation(app)

                % Customização por emissão...
                idx1 = app.SpecIndex(1);
                idx2 = app.SpecIndex(2);

                if ~isempty(app.CallingApp.specData(idx1).UserData.Emissions.UserData{idx2})
                    app.filter_Customization.Value = 1;

                    if ~isempty(app.filterTable)
                        arrayfun(@(x) delete(x), [app.filterTable.roi.handle]); 
                        app.filterTable(:,:) = [];
                    end

                    app.filterTable                         = app.CallingApp.specData(idx1).UserData.Emissions.UserData{idx2}.filterTable;
                    app.pointsTable                         = app.CallingApp.specData(idx1).UserData.Emissions.UserData{idx2}.pointsTable;
                    app.binTable_Type.Value                 = app.CallingApp.specData(idx1).UserData.Emissions.UserData{idx2}.binning_Type;
                    binning_Layout(app)
                    app.binTable_Factor.Value               = app.CallingApp.specData(idx1).UserData.Emissions.UserData{idx2}.binning_Value;
                    app.binTable_Fcn.Value                  = app.CallingApp.specData(idx1).UserData.Emissions.UserData{idx2}.binning_Fcn;
                    app.layout_route_LineStyle.Value        = app.CallingApp.specData(idx1).UserData.Emissions.UserData{idx2}.route_LineStyle;
                    app.layout_route_Color1.BackgroundColor = app.CallingApp.specData(idx1).UserData.Emissions.UserData{idx2}.route_OutColor;
                    app.layout_route_Color2.BackgroundColor = app.CallingApp.specData(idx1).UserData.Emissions.UserData{idx2}.route_InColor;
                    app.layout_route_Size.Value             = app.CallingApp.specData(idx1).UserData.Emissions.UserData{idx2}.route_MarkerSize;
                    app.layout_points_LineStyle.Value       = app.CallingApp.specData(idx1).UserData.Emissions.UserData{idx2}.points_Marker;
                    app.layout_points_Color.BackgroundColor = app.CallingApp.specData(idx1).UserData.Emissions.UserData{idx2}.points_Color;
                    app.layout_points_Size.Value            = app.CallingApp.specData(idx1).UserData.Emissions.UserData{idx2}.points_Size;
                    app.tool_mapPlotSize.Value              = app.CallingApp.specData(idx1).UserData.Emissions.UserData{idx2}.distortion_Size;
                    app.layout_Colormap.Value               = app.CallingApp.specData(idx1).UserData.Emissions.UserData{idx2}.Colormap;
                    if isfield(app.CallingApp.specData(idx1).UserData.Emissions.UserData{idx2}, 'Basemap')
                        app.UIAxes1.Basemap = app.CallingApp.specData(idx1).UserData.Emissions.UserData{idx2}.Basemap;
                    end
                    checkSource(app, 'Write');

                    if app.CallingApp.specData(idx1).UserData.Emissions.UserData{idx2}.distortion_Visibility
                        app.tool_ScatterButton.ImageSource = 'DriveTest_Scatter_ON.png';
                    else
                        app.tool_ScatterButton.ImageSource = 'DriveTest_Scatter_OFF.png';
                    end

                    if app.CallingApp.specData(idx1).UserData.Emissions.UserData{idx2}.density_Visibility
                        app.tool_DensityButton.ImageSource = 'DriveTest_Density_ON.png';
                    else
                        app.tool_DensityButton.ImageSource = 'DriveTest_Density_OFF.png';
                    end

                    points_TreeBuilding(app)
                    plot_layoutCallbacks(app, struct('Source', app.layout_Colormap))

                    % Criação dos ROIs (caso existentes)
                    if ~isempty(app.filterTable)
                        app.filter_roiOptions.SelectedNodes = [];
                        filter_ROISelectionChanged(app)

                        for ii = 1:height(app.filterTable)
                            subtype = app.filterTable.subtype{ii};

                            switch subtype
                                case 'Threshold'
                                    hROI = images.roi.Line(app.UIAxes2, 'Color', 'red', 'MarkerSize', 4, 'LineWidth', 1, 'Deletable', 0, 'InteractionsAllowed', 'translate', 'Tag', 'ROI');
                                otherwise
                                    switch subtype
                                        case 'Circle';    roiFcn = 'Circle';    roiNameArgument = '';
                                        case 'Rectangle'; roiFcn = 'Rectangle'; roiNameArgument = 'Rotatable=true, ';
                                        case 'Polygon';   roiFcn = 'Polygon';   roiNameArgument = '';
                                    end
                                    eval(sprintf('hROI = images.roi.%s(app.UIAxes1, LineWidth=1, Deletable=0, FaceSelectable=0, %sTag="ROI");', roiFcn, roiNameArgument))
                            end

                            fieldsList = fields(app.filterTable.roi(ii).specification);
                            for jj = 1:numel(fieldsList)
                                hROI.(fieldsList{jj}) = app.filterTable.roi(ii).specification.(fieldsList{jj});
                            end

                            addlistener(hROI, 'MovingROI', @app.plot_ROICallback);
                            addlistener(hROI, 'ROIMoved',  @app.plot_ROICallback);
                            addlistener(hROI, 'ObjectBeingDestroyed', @(src, ~)plotFcn.cleanupFcn(src));

                            app.filterTable.roi(ii).handle = hROI;
                        end
                    end

                else
                    app.filter_Customization.Value = 0;

                    idx = find(strcmp(app.pointsTable.type, 'FindPeaks'));
                    if ~isempty(idx)
                        app.pointsTable(idx,:) = [];
                        points_TreeBuilding(app)
                    end
                end

                filter_TreeBuilding(app)
                filter_updateInfo(app)

            catch ME
                event.Source.Value = event.PreviousValue;
                layoutFcn.modalWindow(app.UIFigure, 'ccTools.MessageBox', ME.message);
            end
            
        end

        % Selection changed function: filter_roiOptions
        function filter_ROISelectionChanged(app, event)
            
            if ~isempty(app.filter_roiOptions.SelectedNodes) && ~isempty(app.filter_roiOptions.SelectedNodes.NodeData)
                app.filter_addImage.Enable = 1;
            else
                app.filter_addImage.Enable = 0;
            end
            
        end

        % Selection changed function: filter_Tree
        function filter_TreeSelectionChanged(app, event)
            
            if ~isempty(app.filter_Tree.SelectedNodes)
                idx = app.filter_Tree.SelectedNodes.NodeData;        
    
                for ii = 1:height(app.filterTable)
                    if ii ~= idx
                        set(app.filterTable.roi(ii).handle, 'InteractionsAllowed', 'none')
    
                    else
                        if app.filterTable.type == "Level"
                            set(app.filterTable.roi(ii).handle, 'InteractionsAllowed',  'translate')
                        else
                            set(app.filterTable.roi(ii).handle, 'InteractionsAllowed',  'all')
                        end                    
                    end
                end
                uistack(app.filterTable.roi(idx).handle, "top")
            end
            
        end

        % Image clicked function: filter_addImage
        function filter_addImageCallback(app, event)
            
            NodeData = app.filter_roiOptions.SelectedNodes.NodeData;
            
            app.filter_roiOptions.SelectedNodes = [];
            filter_ROISelectionChanged(app)

            set(app.filter_Tree, 'SelectedNodes', [], 'Enable', 0)
            set(app.filter_ContextMenu.Children, Enable=0)

            plotFcn.axesInteraction.DisableDefaultInteractions([app.UIAxes1, app.UIAxes2])
            pause(.1)

            switch NodeData
                case 1
                    Type = 'Level'; SubType = 'Threshold';                        
                    THR  = min(app.filtTable.chPower);
                    hROI = images.roi.Line(app.UIAxes2, 'Position', [1 THR; height(app.filtTable) THR],  ...
                                                        'Color', 'red', 'MarkerSize', 4, 'LineWidth', 1, ...
                                                        'Deletable', 0, 'InteractionsAllowed', 'translate', 'Tag', 'ROI');
                otherwise
                    switch NodeData
                        case 2; Type = 'Geographic ROI'; SubType = 'Circle';    roiFcn = 'drawcircle';    roiNameArgument = '';
                        case 3; Type = 'Geographic ROI'; SubType = 'Rectangle'; roiFcn = 'drawrectangle'; roiNameArgument = 'Rotatable=true, ';
                        case 4; Type = 'Geographic ROI'; SubType = 'Polygon';   roiFcn = 'drawpolygon';   roiNameArgument = '';
                            
                    end
                    eval(sprintf('hROI = %s(app.UIAxes1, LineWidth=1, Deletable=0, FaceSelectable=0, %sTag="ROI");', roiFcn, roiNameArgument))
            end
            plotFcn.axesInteraction.EnableDefaultInteractions([app.UIAxes1, app.UIAxes2])

            if isempty(hROI.Position)
                delete(hROI)
                
                app.filter_Tree.Enable = 1;
                if ~isempty(app.filter_Tree.Children)
                    app.filter_Tree.SelectedNodes = app.filter_Tree.Children(end);
                    filter_TreeSelectionChanged(app)
                end

                return
            end

            addlistener(hROI, 'MovingROI', @app.plot_ROICallback);
            addlistener(hROI, 'ROIMoved',  @app.plot_ROICallback);
            addlistener(hROI, 'ObjectBeingDestroyed', @(src, ~)plotFcn.cleanupFcn(src));

            app.filterTable(end+1,:) = {Type, SubType, struct('handle', hROI, 'specification', filter_roiSpecification(app, SubType, hROI)), 1};
            filter_TreeBuilding(app)
            filter_updateInfo(app)

        end

        % Menu selected function: filter_delAllButton, filter_delButton
        function filter_delFilter(app, event)
            
            if isempty(app.filterTable)
                return
            end

            switch event.Source
                case app.filter_delButton
                    if isempty(app.filter_Tree.SelectedNodes)
                        return
                    end
                    idx = arrayfun(@(x) x.NodeData, app.filter_Tree.SelectedNodes);

                case app.filter_delAllButton
                    idx = 1:height(app.filterTable);
            end 
    
            if ~isempty(idx)
                arrayfun(@(x) delete(x), [app.filterTable.roi(idx).handle]); 
                app.filterTable(idx,:) = [];

                filter_TreeBuilding(app)
                filter_updateInfo(app)
            end

        end

        % Value changed function: filter_Customization
        function filter_CustomizationCheckBox(app, event)

            try
                checkIndex(app)
                
                idx1 = app.SpecIndex(1);
                idx2 = app.SpecIndex(2);

            catch ME
                app.filter_Customization.Value = event.PreviousValue;
                layoutFcn.modalWindow(app.UIFigure, 'ccTools.MessageBox', ME.message);
                return
            end

            if app.filter_Customization.Value
                app.CallingApp.specData(idx1).UserData.Emissions.UserData{idx2} = ParametersCreation(app);
            else
                app.CallingApp.specData(idx1).UserData.Emissions.UserData{idx2} = [];
            end

        end

        % Selection changed function: points_addTypePanel
        function points_addTypePanelSelectionChanged(app, event)
            
            selectedButton = app.points_addTypePanel.SelectedObject;
            switch selectedButton
                case app.points_addRFDataHub; app.points_addValueGrid.RowHeight = {17,22,22,22,0,0,0,0};
                case app.points_addFindPeaks;  app.points_addValueGrid.RowHeight = {0,0,0,0,17,22,22,22};
            end
            
        end

        % Image clicked function: points_addImage
        function points_addImageImageClicked(app, event)
            
            selectedButton = app.points_addTypePanel.SelectedObject;

            try
                switch selectedButton
                    %-----------------------------------------------------%
                    case app.points_addRFDataHub
                        global RFDataHub
            
                        if isempty(char(app.points_addSubtype1TextArea.Value))
                            return
                        end
                        entryText = char(strjoin(app.points_addSubtype1TextArea.Value, ','));
            
                        switch app.points_addSubtype1DropDown.Value
                            case 'Índices de registros do RFDataHub'
                                idx = regexp(entryText, '#(\d+)', 'tokens');
                                idx = str2double([idx{:}]);
            
                            case 'Lista de frequências'
                                freqList = regexp(entryText, '(\d+.\d+)', 'tokens');
                                freqList = cellfun(@(x) str2double(x), [freqList{:}]);
            
                                filterTempTable = table('Size',          [0, 9],                                                                      ...
                                                        'VariableTypes', {'cell', 'int8', 'int8', 'cell', 'cell', 'int8', 'cell', 'logical', 'cell'}, ...
                                                        'VariableNames', {'Order', 'ID', 'RelatedID', 'Type', 'Operation', 'Column', 'Value', 'Enable', 'uuid'});
            
                                for ii = 1:numel(freqList)
                                    if ii == 1; Order = 'Node';  RelatedID = -1;
                                    else;       Order = 'Child'; RelatedID = 1;
                                    end
                                    filterTempTable(ii,:) = {Order, ii, RelatedID, 'Frequência', '=', 1, {freqList(ii)}, true, ''};
                                end            
                                idx = find(fcn.TableFiltering(RFDataHub, filterTempTable));
                        end
        
                        if ~isempty(idx)
                            NN = numel(idx);
                            distanceArray = deg2km(distance(RFDataHub.Latitude(idx),   RFDataHub.Longitude(idx),  ...
                                                            app.SpecInfo.GPS.Latitude, app.SpecInfo.GPS.Longitude));
                            
                            idx(distanceArray > app.points_addSubtype1Distance.Value) = [];
                            MM = numel(idx);
                            if ~MM
                                error('Não identificada estação de telecomunicações que atenda às especificações...')
                            end
                            
                            newRow = {'RFDataHub', struct('source', app.points_addSubtype1DropDown.Value, 'idx', idx, 'Coordinates', RFDataHub{idx,6:7}), true};
                            points_TableCheck(app, newRow)
        
                            msg = sprintf('Identificada(s) %d estação(ões) de telecomunicações que atende(m) às especificações.', NN);
                            if MM ~= NN
                                msg = sprintf('%s Contudo, apenas %d atende(m) à distância máxima especificada.', msg, MM);
                            end
                        else
                            error('Não identificada estação de telecomunicações que atenda às especificações...')
                        end
                        layoutFcn.modalWindow(app.UIFigure, 'ccTools.MessageBox', msg);
    
                    %---------------------------------------------------------%
                    case app.points_addFindPeaks
                        [idx, Coordinates] = points_FindPeaks(app, app.points_addSubtype2DropDown.Value, app.points_addSubtype2NPeaks.Value, app.points_addSubtype2Distance.Value/1000);
                        newRow = {'FindPeaks', struct('source', app.points_addSubtype2DropDown.Value, 'idx', idx, 'Coordinates', Coordinates), true};
                        points_TableCheck(app, newRow)

                        msg = sprintf('Identificado(s) %d ponto(s) que atende(m) às especificações.', numel(idx));
                        layoutFcn.modalWindow(app.UIFigure, 'ccTools.MessageBox', msg);
                end

            catch ME
                layoutFcn.modalWindow(app.UIFigure, 'ccTools.MessageBox', ME.message);
                return
            end

            points_TreeBuilding(app)
            plotFcn.axesDraw.DriveTestPointsPlot(app.UIAxes1, ParametersCreation(app))
            plotFcn.axesStackingOrder.execute('winDriveTest', app.UIAxes1)

        end

        % Value changed function: points_addSubtype1TextArea
        function points_addSubtype1TextAreaValueChanged(app, event)

            app.points_addSubtype1TextArea.Value = strtrim(app.points_addSubtype1TextArea.Value);
            
        end

        % Callback function: points_Tree
        function points_TreeCheckedNodesChanged(app, event)
            
            visibleNodeData   = arrayfun(@(x) x.NodeData, app.points_Tree.CheckedNodes);
            invisibleNodeData = setdiff(1:height(app.pointsTable), visibleNodeData);

            app.pointsTable.visible(visibleNodeData)   = true;
            app.pointsTable.visible(invisibleNodeData) = false;

            plotFcn.axesDraw.DriveTestPointsPlot(app.UIAxes1, ParametersCreation(app))
            plotFcn.axesStackingOrder.execute('winDriveTest', app.UIAxes1)
            
        end

        % Menu selected function: points_delButton
        function points_delButtonMenuSelected(app, event)
            
            if isempty(app.pointsTable) || isempty(app.points_Tree.SelectedNodes)
                return
            end

            idx = arrayfun(@(x) x.NodeData, app.points_Tree.SelectedNodes);
    
            if ~isempty(idx)
                app.pointsTable(idx,:) = [];

                points_TreeBuilding(app)
                plotFcn.axesDraw.DriveTestPointsPlot(app.UIAxes1, ParametersCreation(app))
                plotFcn.axesStackingOrder.execute('winDriveTest', app.UIAxes1)
            end

        end

        % Value changed function: binTable_Type
        function binTable_TypeChanged(app, event)

            binning_Layout(app)
            binTable_ValueChanged(app)
            
        end

        % Value changed function: binTable_Factor, binTable_Fcn
        function binTable_ValueChanged(app, event)
            
            d = uiprogressdlg(app.UIFigure, "Indeterminate", "on", "Message", "Em andamento...", "Interpreter", "html");
            
            binning_binTable(app)
            binning_UITable(app)

            if app.layout_mapPlotSource_binData.Value
                plot_MainControl(app)
            else                
                h = findall(app.UIAxes2, 'Tag', 'binTable');
                h.YData = app.binTable.binPower(app.filtTable.FK2);
            end

            filter_updateProp(app)

            delete(d)
            
        end

        % Selection changed function: layout_mapPlotSourcePanel
        function plot_RefreshCallback(app, event)
            
            d = uiprogressdlg(app.UIFigure, "Indeterminate", "on", "Message", "Em andamento...", "Interpreter", "html");
            
            plot_MainControl(app)
            filter_updateProp(app)

            delete(d)

        end

        % Image clicked function: tool_DensityButton, tool_ScatterButton
        function plot_DistortionOrDensityVisibility(app, event)
            
            hDistortion = findobj(app.UIAxes1, 'Tag', 'Distortion');
            hDensity    = findobj(app.UIAxes1, 'Tag', 'Density');

            switch event.Source
                case app.tool_ScatterButton
                    if hDistortion.Visible
                        hDistortion.Visible = 0;
                        app.tool_ScatterButton.ImageSource = 'DriveTest_Scatter_OFF.png';
                    else
                        hDistortion.Visible = 1;
                        hDensity.Visible = 0;

                        app.tool_ScatterButton.ImageSource = 'DriveTest_Scatter_ON.png';
                        app.tool_DensityButton.ImageSource = 'DriveTest_Density_OFF.png';
                    end

                case app.tool_DensityButton
                    if hDensity.Visible
                        hDensity.Visible = 0;
                        app.tool_DensityButton.ImageSource = 'DriveTest_Density_OFF.png';
                    else
                        hDistortion.Visible = 0;
                        hDensity.Visible = 1;

                        app.tool_ScatterButton.ImageSource = 'DriveTest_Scatter_OFF.png';
                        app.tool_DensityButton.ImageSource = 'DriveTest_Density_ON.png';
                    end
            end

        end

        % Button pushed function: layout_Car_Color, layout_points_Color, 
        % ...and 2 other components
        function plot_colorCallbacks(app, event)
            
            initialColor  = event.Source.BackgroundColor;
            selectedColor = uisetcolor(initialColor);
            figure(app.UIFigure)

            if ~isequal(initialColor, selectedColor)
                event.Source.BackgroundColor = selectedColor;

                switch event.Source
                    case app.layout_route_Color1
                        set(findobj(app.UIAxes1.Children, 'Tag', 'OutRoute'), 'Color', selectedColor, 'MarkerFaceColor', selectedColor, 'MarkerEdgeColor', selectedColor)

                    case app.layout_route_Color2
                        set(findobj(app.UIAxes1.Children, 'Tag', 'InRoute'),  'Color', selectedColor, 'MarkerFaceColor', selectedColor, 'MarkerEdgeColor', selectedColor)

                    case app.layout_Car_Color
                        set(app.hCar, 'MarkerFaceColor', selectedColor)

                    case app.layout_points_Color
                        hPoints = findobj(app.UIAxes1.Children, 'Tag', 'Points');
                        for ii = 1:numel(hPoints)
                            if strcmp(hPoints(ii).MarkerFaceColor, 'none')
                                set(hPoints(ii), 'Color', selectedColor, 'MarkerEdgeColor', selectedColor)
                            else
                                set(hPoints(ii), 'Color', selectedColor, 'MarkerFaceColor', selectedColor, 'MarkerEdgeColor', selectedColor)
                            end
                        end
                end
            end

            filter_updateProp(app)

        end

        % Callback function: layout_Car_LineStyle, layout_Car_Size, 
        % ...and 5 other components
        function plot_layoutCallbacks(app, event)
            
            try
                switch event.Source
                    case app.layout_route_LineStyle
                        switch event.Value
                            case 'none'; markerSize = 1;
                            otherwise;   markerSize = 8*app.layout_route_Size.Value;
                        end
                        set(findobj(app.UIAxes1.Children, 'Tag', 'OutRoute'),                          'MarkerSize', markerSize)
                        set(findobj(app.UIAxes1.Children, 'Tag', 'InRoute'), 'LineStyle', event.Value, 'MarkerSize', markerSize)

                    case app.layout_route_Size
                        if ~strcmp(app.layout_route_LineStyle.Value, 'none')
                            set(findobj(app.UIAxes1.Children, 'Tag', 'OutRoute'), 'MarkerSize', 8*event.Value)
                            set(findobj(app.UIAxes1.Children, 'Tag', 'InRoute'),  'MarkerSize', 8*event.Value)
                        end

                    case app.layout_Car_Size
                        set(app.hCar, 'SizeData', 10*event.Value)

                    case app.layout_Car_LineStyle
                        set(app.hCar, 'Marker', event.Value)

                    case app.layout_points_Size
                        set(findobj(app.UIAxes1.Children, 'Tag', 'Points'), 'MarkerSize', event.Value)

                    case app.layout_points_LineStyle
                        set(findobj(app.UIAxes1.Children, 'Tag', 'Points'), 'Marker', event.Value)

                    case app.layout_Colormap
                        colormap(app.UIAxes1, app.layout_Colormap.Value)
                        app.UIAxes1.Colormap(1,:) = [0,0,0];
                end

                filter_updateProp(app)
            catch
            end

        end

        % Value changed function: tool_mapPlotSize
        function plot_SizeChangedCallback(app, event)

            d = uiprogressdlg(app.UIFigure, "Indeterminate", "on", "Message", "Em andamento...", "Interpreter", "html");

            set(findobj(app.UIAxes1, 'Tag', 'Distortion'), 'SizeData', 20*event.Value)
            set(findobj(app.UIAxes1, 'Tag', 'Density'),    'Radius',  100*event.Value)
            drawnow

            filter_updateProp(app)

            delete(d)

        end

        % Value changed function: layout_chPowerSource_binTable, 
        % ...and 1 other component
        function plot_SourceChanged(app, event)
            
            switch event.Source
                case app.layout_chPowerSource_rawTable; Tag = 'rawTable';
                case app.layout_chPowerSource_binTable; Tag = 'binTable';
            end

            h = findall(app.UIAxes2, 'Tag', Tag);
            if event.Source.Value
                h.Visible = 1;
            else
                h.Visible = 0;
            end

        end

        % Value changed function: layout_chPowerSource_smoothed
        function plot_smoothedDataVisibility(app, event)
            
            if app.layout_chPowerSource_smoothed.Value
                set(app.layout_Smoothed_Grid.Children, 'Enable', 1)
                set(findobj(app.UIAxes2.Children, 'Tag', 'SmoothedData'), Visible=1)

            else
                set(app.layout_Smoothed_Grid.Children, 'Enable', 0)
                set(findobj(app.UIAxes2.Children, 'Tag', 'SmoothedData'), Visible=0)
            end            
            
        end

        % Value changed function: layout_Smoothed_Factor, 
        % ...and 1 other component
        function plot_smoothedDataUpdate(app, event)

            d = uiprogressdlg(app.UIFigure, "Indeterminate", "on", "Message", "Em andamento...", "Interpreter", "html");            
            set(findobj(app.UIAxes2.Children, 'Tag', 'SmoothedData'), YData=smoothdata(app.filtTable.chPower, app.layout_Smoothed_Type.Value, 'SmoothingFactor', app.layout_Smoothed_Factor.Value))
            delete(d)
            
        end

        % Image clicked function: layout_mapPlotLock
        function plot_LockImageClicked(app, event)

            if app.CallingApp.play_Tree.Enable
                idx = [];
                for ii = 1:numel(app.CallingApp.specData)
                    if isequal(rmfield(struct(app.CallingApp.specData(ii)), 'UserData'), rmfield(struct(app.SpecInfo), 'UserData'))
                        idx = ii;
                        break
                    end
                end
    
                if isempty(idx)
                    layoutFcn.modalWindow(app.UIFigure, 'ccTools.MessageBox', 'Não identificado o fluxo de dados...');
                    return
                end

                if app.CallingApp.play_Tree.SelectedNodes.NodeData ~= idx
                    if isempty(app.CallingApp.play_PlotPanel.UserData) || (app.CallingApp.play_PlotPanel.UserData.NodeData ~= idx)
                        hTreeNode      = findall(app.CallingApp.play_Tree, '-not', 'Type', 'uitree');

                        hTreeNodeData  = arrayfun(@(x) x.NodeData, hTreeNode, 'UniformOutput', false);
                        hTreeNodeIndex = find(cellfun(@(x) isequal(x, idx), hTreeNodeData), 1); 

                        if isempty(hTreeNodeIndex)
                            layoutFcn.modalWindow(app.UIFigure, 'ccTools.MessageBox', 'Não identificado o fluxo de dados...');
                            return
                        end

                        app.CallingApp.auxiliarWinFunction(app, 'lockMainTree', hTreeNode(hTreeNodeIndex))
                    end
                end

                app.CallingApp.play_Tree.Enable = 0;
                set(app.CallingApp.play_Lock, 'Enable', 0, 'ImageSource', 'LT_lockClose.png')
                app.layout_mapPlotLock.ImageSource = 'LT_lockClose.png';

            else
                app.CallingApp.play_Tree.Enable = 1;
                set(app.CallingApp.play_Lock, 'Enable', 1, 'ImageSource', 'LT_lockOpen.png')
                app.layout_mapPlotLock.ImageSource = 'LT_lockOpen.png';
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appAnalise: Drive-test';
            app.UIFigure.Icon = 'LR_icon.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.Grid = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.Grid);
            app.LeftPanelGrid = uigridlayout(app.LeftPanel);
            app.menu_MainGrid = uigridlayout(app.LeftPanelGrid);
            app.menu_Image = uiimage(app.menu_MainGrid);
            app.menu_Image.ImageSource = 'LT_LineH.png';
            app.menu_Button1Grid = uigridlayout(app.menu_MainGrid);
            app.menu_Button1Label = uilabel(app.menu_Button1Grid);
            app.menu_Button1Label.Text = 'Aspectos gerais';
            app.menu_Button1Icon = uiimage(app.menu_Button1Grid);
            app.menu_Button1Icon.ImageClickedFcn = createCallbackFcn(app, @layout_LeftPanelSelectedTabControl, true);
            app.menu_Button1Icon.Tag = '1';
            app.menu_Button1Icon.ImageSource = 'LT_Dots6.png';
            app.menu_Button2Grid = uigridlayout(app.menu_MainGrid);
            app.menu_Button2Label = uilabel(app.menu_Button2Grid);
            app.menu_Button2Label.Text = 'Pontos de interesse';
            app.menu_Button2Icon = uiimage(app.menu_Button2Grid);
            app.menu_Button2Icon.ImageClickedFcn = createCallbackFcn(app, @layout_LeftPanelSelectedTabControl, true);
            app.menu_Button2Icon.Tag = '2';
            app.menu_Button2Icon.ImageSource = 'LT_Pin2.png';
            app.menu_Button3Grid = uigridlayout(app.menu_MainGrid);
            app.menu_Button3Label = uilabel(app.menu_Button3Grid);
            app.menu_Button3Label.Text = 'Data binning';
            app.menu_Button3Icon = uiimage(app.menu_Button3Grid);
            app.menu_Button3Icon.ImageClickedFcn = createCallbackFcn(app, @layout_LeftPanelSelectedTabControl, true);
            app.menu_Button3Icon.Tag = '3';
            app.menu_Button3Icon.ImageSource = 'LT_Detection2.png';
            app.menu_Button4Grid = uigridlayout(app.menu_MainGrid);
            app.menu_Button4Label = uilabel(app.menu_Button4Grid);
            app.menu_Button4Label.Text = 'Layout';
            app.menu_Button4Icon = uiimage(app.menu_Button4Grid);
            app.menu_Button4Icon.ImageClickedFcn = createCallbackFcn(app, @layout_LeftPanelSelectedTabControl, true);
            app.menu_Button4Icon.Tag = '4';
            app.menu_Button4Icon.ImageSource = 'LT_Layers2.png';
            app.Tab1_Panel = uigridlayout(app.LeftPanelGrid);
            app.emissionListGrid = uigridlayout(app.Tab1_Panel);
            app.emissionListLabel = uilabel(app.emissionListGrid);
            app.emissionListLabel.Text = 'Emissão';
            app.emissionListRefresh = uiimage(app.emissionListGrid);
            app.emissionListRefresh.ImageClickedFcn = createCallbackFcn(app, @emission_RefreshListImageClicked, true);
            app.emissionListRefresh.ImageSource = 'LT_reload.png';
            app.emissionList = uidropdown(app.Tab1_Panel);
            app.emissionList.Items = {};
            app.emissionList.ValueChangedFcn = createCallbackFcn(app, @emission_ValueChanged, true);
            app.emissionList.Value = {};
            app.emissionMetaData = uitextarea(app.Tab1_Panel);
            app.emissionMetaData.Editable = 'off';
            app.filter_TreeLabel = uilabel(app.Tab1_Panel);
            app.filter_TreeLabel.Text = 'Filtros';
            app.filter_roiOptions = uitree(app.Tab1_Panel);
            app.filter_roiOptions.SelectionChangedFcn = createCallbackFcn(app, @filter_ROISelectionChanged, true);
            app.levelFilterNode = uitreenode(app.filter_roiOptions);
            app.levelFilterNode.Icon = 'LT_OCC.png';
            app.levelFilterNode.Text = 'NÍVEL';
            app.levelFilter_thr = uitreenode(app.levelFilterNode);
            app.levelFilter_thr.NodeData = 1;
            app.levelFilter_thr.Text = 'THRESHOLD';
            app.geoFilterNode = uitreenode(app.filter_roiOptions);
            app.geoFilterNode.Icon = 'DriveTest_Scatter_OFF.png';
            app.geoFilterNode.Text = 'GEOGRÁFICO';
            app.geoFilter_Circle = uitreenode(app.geoFilterNode);
            app.geoFilter_Circle.NodeData = 2;
            app.geoFilter_Circle.Text = 'CÍRCULO';
            app.geoFilter_Rectangle = uitreenode(app.geoFilterNode);
            app.geoFilter_Rectangle.NodeData = 3;
            app.geoFilter_Rectangle.Text = 'RETÂNGULO';
            app.geoFilter_Polygon = uitreenode(app.geoFilterNode);
            app.geoFilter_Polygon.NodeData = 4;
            app.geoFilter_Polygon.Text = 'POLÍGONO';
            app.filter_addImageGrid = uigridlayout(app.Tab1_Panel);
            app.filter_addImage = uiimage(app.filter_addImageGrid);
            app.filter_addImage.ImageClickedFcn = createCallbackFcn(app, @filter_addImageCallback, true);
            app.filter_addImage.Enable = 'off';
            app.filter_addImage.ImageSource = 'LT_play.png';
            app.filter_Tree = uitree(app.Tab1_Panel);
            app.filter_Tree.SelectionChangedFcn = createCallbackFcn(app, @filter_TreeSelectionChanged, true);
            app.filter_Customization = uicheckbox(app.Tab1_Panel);
            app.filter_Customization.ValueChangedFcn = createCallbackFcn(app, @filter_CustomizationCheckBox, true);
            app.filter_Customization.Text = 'Customizar controles da análise e do plot, habilitando a inclusão do plot em relatório.';
            app.Tab2_Panel = uigridlayout(app.LeftPanelGrid);
            app.points_TreeLabel = uilabel(app.Tab2_Panel);
            app.points_TreeLabel.Text = 'Pontos de interesse';
            app.points_addTypePanel = uibuttongroup(app.Tab2_Panel);
            app.points_addTypePanel.SelectionChangedFcn = createCallbackFcn(app, @points_addTypePanelSelectionChanged, true);
            app.points_addRFDataHub = uiradiobutton(app.points_addTypePanel);
            app.points_addRFDataHub.Text = {'Estações de telecomunicações'; '(RFDataHub)'};
            app.points_addRFDataHub.Value = true;
            app.points_addFindPeaks = uiradiobutton(app.points_addTypePanel);
            app.points_addFindPeaks.Text = {'Picos da potência'; 'do canal'};
            app.points_addValuePanel = uipanel(app.Tab2_Panel);
            app.points_addValueGrid = uigridlayout(app.points_addValuePanel);
            app.points_addSubtype1Label = uilabel(app.points_addValueGrid);
            app.points_addSubtype1Label.Text = 'Tipo de registro:';
            app.points_addSubtype1DropDown = uidropdown(app.points_addValueGrid);
            app.points_addSubtype1DropDown.Items = {'Índices de registros do RFDataHub', 'Lista de frequências'};
            app.points_addSubtype1DropDown.Value = 'Lista de frequências';
            app.points_addSubtype1TextArea = uitextarea(app.points_addValueGrid);
            app.points_addSubtype1TextArea.ValueChangedFcn = createCallbackFcn(app, @points_addSubtype1TextAreaValueChanged, true);
            app.points_addSubtype1DistanceLabel = uilabel(app.points_addValueGrid);
            app.points_addSubtype1DistanceLabel.Text = 'Distância máxima entre estação e local da monitoração (km):';
            app.points_addSubtype1Distance = uieditfield(app.points_addValueGrid, 'numeric');
            app.points_addSubtype1Distance.Limits = [1 Inf];
            app.points_addSubtype1Distance.RoundFractionalValues = 'on';
            app.points_addSubtype1Distance.ValueDisplayFormat = '%d';
            app.points_addSubtype1Distance.Value = 200;
            app.points_addSubtype2Label = uilabel(app.points_addValueGrid);
            app.points_addSubtype2Label.Text = 'Fonte da informação:';
            app.points_addSubtype2DropDown = uidropdown(app.points_addValueGrid);
            app.points_addSubtype2DropDown.Items = {'Dados brutos', 'Dados processados (Data Binning)', 'Curva suave'};
            app.points_addSubtype2DropDown.Value = 'Dados brutos';
            app.points_addSubtype2NPeaksLabel = uilabel(app.points_addValueGrid);
            app.points_addSubtype2NPeaksLabel.Text = 'Número de picos:';
            app.points_addSubtype2NPeaks = uispinner(app.points_addValueGrid);
            app.points_addSubtype2NPeaks.Limits = [1 100];
            app.points_addSubtype2NPeaks.RoundFractionalValues = 'on';
            app.points_addSubtype2NPeaks.ValueDisplayFormat = '%.0f';
            app.points_addSubtype2NPeaks.Value = 1;
            app.points_addSubtype2DistanceLabel = uilabel(app.points_addValueGrid);
            app.points_addSubtype2DistanceLabel.Text = 'Distância mínima entre picos (metros):';
            app.points_addSubtype2Distance = uispinner(app.points_addValueGrid);
            app.points_addSubtype2Distance.Step = 100;
            app.points_addSubtype2Distance.Limits = [0 10000];
            app.points_addSubtype2Distance.RoundFractionalValues = 'on';
            app.points_addSubtype2Distance.ValueDisplayFormat = '%.0f';
            app.points_addSubtype2Distance.Value = 1000;
            app.points_addImage = uiimage(app.Tab2_Panel);
            app.points_addImage.ImageClickedFcn = createCallbackFcn(app, @points_addImageImageClicked, true);
            app.points_addImage.ImageSource = 'LT_Add.png';
            app.points_Tree = uitree(app.Tab2_Panel, 'checkbox');
            % Assign Checked Nodes
            app.points_Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @points_TreeCheckedNodesChanged, true);
            app.Tab3_Panel = uigridlayout(app.LeftPanelGrid);
            app.binTable_Label = uilabel(app.Tab3_Panel);
            app.binTable_Label.Text = 'Data binning';
            app.binTable_Panel = uipanel(app.Tab3_Panel);
            app.binTable_Grid1 = uigridlayout(app.binTable_Panel);
            app.binTable_TypeLabel = uilabel(app.binTable_Grid1);
            app.binTable_TypeLabel.Text = 'Tipo';
            app.binTable_Type = uidropdown(app.binTable_Grid1);
            app.binTable_Type.Items = {'Grid retangular - Comprimento bin', 'Grid retangular - Coordenadas geográficas truncadas'};
            app.binTable_Type.ValueChangedFcn = createCallbackFcn(app, @binTable_TypeChanged, true);
            app.binTable_Type.Value = 'Grid retangular - Comprimento bin';
            app.binTable_FactorLabel = uilabel(app.binTable_Grid1);
            app.binTable_FactorLabel.Interpreter = 'html';
            app.binTable_FactorLabel.Text = 'Comprimento <i>bin</i> (metros):';
            app.binTable_Factor = uispinner(app.binTable_Grid1);
            app.binTable_Factor.Step = 50;
            app.binTable_Factor.Limits = [50 1500];
            app.binTable_Factor.RoundFractionalValues = 'on';
            app.binTable_Factor.ValueDisplayFormat = '%.0f';
            app.binTable_Factor.ValueChangedFcn = createCallbackFcn(app, @binTable_ValueChanged, true);
            app.binTable_Factor.Value = 100;
            app.binTable_FcnLabel = uilabel(app.binTable_Grid1);
            app.binTable_FcnLabel.Text = 'Operação de sumarização:';
            app.binTable_Fcn = uidropdown(app.binTable_Grid1);
            app.binTable_Fcn.Items = {'min', 'mean', 'median', 'rms', 'max'};
            app.binTable_Fcn.ValueChangedFcn = createCallbackFcn(app, @binTable_ValueChanged, true);
            app.binTable_Fcn.Value = 'mean';
            app.binTable_UITable = uitable(app.Tab3_Panel);
            app.binTable_UITable.ColumnName = {'ID'; 'LATITUDE'; 'LONGITUDE'; 'AMOSTRAS'};
            app.binTable_UITable.RowName = {};
            app.binTable_UITable.SelectionType = 'row';
            app.binTable_Info = uilabel(app.Tab3_Panel);
            app.binTable_Info.Text = '';
            app.Tab4_Panel = uigridlayout(app.LeftPanelGrid);
            app.layout_mapPlotTitle = uilabel(app.Tab4_Panel);
            app.layout_mapPlotTitle.Text = 'Eixo geográfico';
            app.layout_mapPlotLock = uiimage(app.Tab4_Panel);
            app.layout_mapPlotLock.ImageClickedFcn = createCallbackFcn(app, @plot_LockImageClicked, true);
            app.layout_mapPlotLock.ImageSource = 'LT_lockClose.png';
            app.layout_mapPlotPanel = uipanel(app.Tab4_Panel);
            app.layout_mapPlotGrid = uigridlayout(app.layout_mapPlotPanel);
            app.layout_mapPlotSourceLabel = uilabel(app.layout_mapPlotGrid);
            app.layout_mapPlotSourceLabel.Text = 'Fonte da informação:';
            app.layout_mapPlotSourcePanel = uibuttongroup(app.layout_mapPlotGrid);
            app.layout_mapPlotSourcePanel.SelectionChangedFcn = createCallbackFcn(app, @plot_RefreshCallback, true);
            app.layout_mapPlotSource_rawData = uiradiobutton(app.layout_mapPlotSourcePanel);
            app.layout_mapPlotSource_rawData.Text = 'Dados brutos';
            app.layout_mapPlotSource_rawData.Value = true;
            app.layout_mapPlotSource_binData = uiradiobutton(app.layout_mapPlotSourcePanel);
            app.layout_mapPlotSource_binData.Text = 'Dados processados (Data Binning)';
            app.layout_mapPlotLayoutLabel = uilabel(app.layout_mapPlotGrid);
            app.layout_mapPlotLayoutLabel.Text = 'Layout:';
            app.layout_mapPlotLayoutPanel = uipanel(app.layout_mapPlotGrid);
            app.layout_mapPlotLayoutGrid = uigridlayout(app.layout_mapPlotLayoutPanel);
            app.layout_route_Label = uilabel(app.layout_mapPlotLayoutGrid);
            app.layout_route_Label.Text = 'Rota:';
            app.layout_route_LineStyle = uidropdown(app.layout_mapPlotLayoutGrid);
            app.layout_route_LineStyle.Items = {'none', ':', '-'};
            app.layout_route_LineStyle.ValueChangedFcn = createCallbackFcn(app, @plot_layoutCallbacks, true);
            app.layout_route_LineStyle.Value = '-';
            app.layout_route_Panel = uipanel(app.layout_mapPlotLayoutGrid);
            app.layout_route_Grid = uigridlayout(app.layout_route_Panel);
            app.layout_route_Color1 = uibutton(app.layout_route_Grid, 'push');
            app.layout_route_Color1.ButtonPushedFcn = createCallbackFcn(app, @plot_colorCallbacks, true);
            app.layout_route_Color1.Text = '';
            app.layout_route_Color2 = uibutton(app.layout_route_Grid, 'push');
            app.layout_route_Color2.ButtonPushedFcn = createCallbackFcn(app, @plot_colorCallbacks, true);
            app.layout_route_Color2.Text = '';
            app.layout_route_Size = uislider(app.layout_route_Grid);
            app.layout_route_Size.Limits = [1 9];
            app.layout_route_Size.MajorTicks = [];
            app.layout_route_Size.MajorTickLabels = {''};
            app.layout_route_Size.ValueChangingFcn = createCallbackFcn(app, @plot_layoutCallbacks, true);
            app.layout_route_Size.MinorTicks = [];
            app.layout_route_Size.Value = 1;
            app.layout_Car_Label = uilabel(app.layout_mapPlotLayoutGrid);
            app.layout_Car_Label.Text = 'Veículo:';
            app.layout_Car_LineStyle = uidropdown(app.layout_mapPlotLayoutGrid);
            app.layout_Car_LineStyle.Items = {'none', 'o', 'square', '^'};
            app.layout_Car_LineStyle.ValueChangedFcn = createCallbackFcn(app, @plot_layoutCallbacks, true);
            app.layout_Car_LineStyle.Value = 'square';
            app.layout_Car_Panel = uipanel(app.layout_mapPlotLayoutGrid);
            app.layout_Car_Grid = uigridlayout(app.layout_Car_Panel);
            app.layout_Car_Color = uibutton(app.layout_Car_Grid, 'push');
            app.layout_Car_Color.ButtonPushedFcn = createCallbackFcn(app, @plot_colorCallbacks, true);
            app.layout_Car_Color.Text = '';
            app.layout_Car_Size = uislider(app.layout_Car_Grid);
            app.layout_Car_Size.Limits = [1 19];
            app.layout_Car_Size.MajorTicks = [];
            app.layout_Car_Size.MajorTickLabels = {''};
            app.layout_Car_Size.ValueChangingFcn = createCallbackFcn(app, @plot_layoutCallbacks, true);
            app.layout_Car_Size.MinorTicks = [];
            app.layout_Car_Size.Value = 10;
            app.layout_points_Label = uilabel(app.layout_mapPlotLayoutGrid);
            app.layout_points_Label.Text = 'Pontos interesse:';
            app.layout_points_LineStyle = uidropdown(app.layout_mapPlotLayoutGrid);
            app.layout_points_LineStyle.Items = {'none', 'o', 'square', '^'};
            app.layout_points_LineStyle.ValueChangedFcn = createCallbackFcn(app, @plot_layoutCallbacks, true);
            app.layout_points_LineStyle.Value = '^';
            app.layout_points_Panel = uipanel(app.layout_mapPlotLayoutGrid);
            app.layout_points_Grid = uigridlayout(app.layout_points_Panel);
            app.layout_points_Color = uibutton(app.layout_points_Grid, 'push');
            app.layout_points_Color.ButtonPushedFcn = createCallbackFcn(app, @plot_colorCallbacks, true);
            app.layout_points_Color.Text = '';
            app.layout_points_Size = uislider(app.layout_points_Grid);
            app.layout_points_Size.Limits = [6 12];
            app.layout_points_Size.MajorTicks = [];
            app.layout_points_Size.MajorTickLabels = {''};
            app.layout_points_Size.ValueChangingFcn = createCallbackFcn(app, @plot_layoutCallbacks, true);
            app.layout_points_Size.MinorTicks = [];
            app.layout_points_Size.Value = 9;
            app.layout_ColormapLabel = uilabel(app.layout_mapPlotLayoutGrid);
            app.layout_ColormapLabel.Text = {'Mapa de'; 'cor:'};
            app.layout_Colormap = uidropdown(app.layout_mapPlotLayoutGrid);
            app.layout_Colormap.Items = {'hot', 'summer', 'winter', 'jet'};
            app.layout_Colormap.ValueChangedFcn = createCallbackFcn(app, @plot_layoutCallbacks, true);
            app.layout_Colormap.Value = 'jet';
            app.layout_cartesianPlotTitle = uilabel(app.Tab4_Panel);
            app.layout_cartesianPlotTitle.Text = 'Eixo cartesiano';
            app.layout_cartesianPlotPanel = uipanel(app.Tab4_Panel);
            app.layout_cartesianPlotGrid = uigridlayout(app.layout_cartesianPlotPanel);
            app.layout_cartesianPlotLabel = uilabel(app.layout_cartesianPlotGrid);
            app.layout_cartesianPlotLabel.Text = 'Fonte da informação:';
            app.layout_cartesianPlotSubPanel = uipanel(app.layout_cartesianPlotGrid);
            app.layout_cartesianPlotSubGrid = uigridlayout(app.layout_cartesianPlotSubPanel);
            app.layout_chPowerSource_rawTable = uicheckbox(app.layout_cartesianPlotSubGrid);
            app.layout_chPowerSource_rawTable.ValueChangedFcn = createCallbackFcn(app, @plot_SourceChanged, true);
            app.layout_chPowerSource_rawTable.Text = 'Dados brutos';
            app.layout_chPowerSource_rawTable.Value = true;
            app.layout_chPowerSource_binTable = uicheckbox(app.layout_cartesianPlotSubGrid);
            app.layout_chPowerSource_binTable.ValueChangedFcn = createCallbackFcn(app, @plot_SourceChanged, true);
            app.layout_chPowerSource_binTable.Text = 'Dados processados (Data Binning)';
            app.layout_chPowerSource_smoothed = uicheckbox(app.layout_cartesianPlotSubGrid);
            app.layout_chPowerSource_smoothed.ValueChangedFcn = createCallbackFcn(app, @plot_smoothedDataVisibility, true);
            app.layout_chPowerSource_smoothed.Text = 'Curva suave';
            app.layout_Smoothed_Panel = uipanel(app.layout_cartesianPlotSubGrid);
            app.layout_Smoothed_Grid = uigridlayout(app.layout_Smoothed_Panel);
            app.layout_Smoothed_TypeLabel = uilabel(app.layout_Smoothed_Grid);
            app.layout_Smoothed_TypeLabel.Enable = 'off';
            app.layout_Smoothed_TypeLabel.Text = 'Tipo:';
            app.layout_Smoothed_Type = uidropdown(app.layout_Smoothed_Grid);
            app.layout_Smoothed_Type.Items = {'movmean', 'movmedian', 'gaussian', 'lowess', 'loess', 'rlowess', 'rloess', 'sgolay'};
            app.layout_Smoothed_Type.ValueChangedFcn = createCallbackFcn(app, @plot_smoothedDataUpdate, true);
            app.layout_Smoothed_Type.Enable = 'off';
            app.layout_Smoothed_Type.Value = 'gaussian';
            app.layout_Smoothed_FactorLabel = uilabel(app.layout_Smoothed_Grid);
            app.layout_Smoothed_FactorLabel.Enable = 'off';
            app.layout_Smoothed_FactorLabel.Text = 'Fator:';
            app.layout_Smoothed_Factor = uispinner(app.layout_Smoothed_Grid);
            app.layout_Smoothed_Factor.Step = 0.1;
            app.layout_Smoothed_Factor.Limits = [0 1];
            app.layout_Smoothed_Factor.ValueDisplayFormat = '%.2f';
            app.layout_Smoothed_Factor.ValueChangedFcn = createCallbackFcn(app, @plot_smoothedDataUpdate, true);
            app.layout_Smoothed_Factor.Enable = 'off';
            app.layout_Smoothed_Factor.Value = 0.25;
            app.LeftPanelVisibility = uiimage(app.Grid);
            app.LeftPanelVisibility.ImageClickedFcn = createCallbackFcn(app, @layout_LeftPanelVisibilityControl, true);
            app.LeftPanelVisibility.ImageSource = 'LT_HideLeft.png';
            app.toolGrid = uigridlayout(app.Grid);
            app.tool_ExportButton = uibutton(app.toolGrid, 'push');
            app.tool_ExportButton.ButtonPushedFcn = createCallbackFcn(app, @tool_ExportFileButtonPushed, true);
            app.tool_ExportButton.Icon = 'Export_24.png';
            app.tool_ExportButton.Tooltip = {'Exportar tabela'};
            app.tool_ExportButton.Text = '';
            app.tool_Serarator1 = uiimage(app.toolGrid);
            app.tool_Serarator1.Enable = 'off';
            app.tool_Serarator1.ImageSource = 'LT_LineV.png';
            app.tool_ScatterButton = uiimage(app.toolGrid);
            app.tool_ScatterButton.ImageClickedFcn = createCallbackFcn(app, @plot_DistortionOrDensityVisibility, true);
            app.tool_ScatterButton.Tooltip = {'Distorção'};
            app.tool_ScatterButton.ImageSource = 'DriveTest_Scatter_ON.png';
            app.tool_DensityButton = uiimage(app.toolGrid);
            app.tool_DensityButton.ImageClickedFcn = createCallbackFcn(app, @plot_DistortionOrDensityVisibility, true);
            app.tool_DensityButton.Tooltip = {'Densidade'};
            app.tool_DensityButton.ImageSource = 'DriveTest_Density_OFF.png';
            app.tool_mapPlotSize = uislider(app.toolGrid);
            app.tool_mapPlotSize.Limits = [1 19];
            app.tool_mapPlotSize.MajorTicks = [1 10 19];
            app.tool_mapPlotSize.MajorTickLabels = {''};
            app.tool_mapPlotSize.ValueChangedFcn = createCallbackFcn(app, @plot_SizeChangedCallback, true);
            app.tool_mapPlotSize.MinorTicks = [1.6 2.2 2.8 3.4 4 4.6 5.2 5.8 6.4 7 7.6 8.2 8.8 9.4 10 10.6 11.2 11.8 12.4 13 13.6 14.2 14.8 15.4 16 16.6 17.2 17.8 18.4];
            app.tool_mapPlotSize.Value = 1;
            app.tool_Serarator2 = uiimage(app.toolGrid);
            app.tool_Serarator2.Enable = 'off';
            app.tool_Serarator2.ImageSource = 'LT_LineV.png';
            app.tool_SelectedEmision = uilabel(app.toolGrid);
            app.tool_SelectedEmision.Text = {'R&S FSL'; 'ID1: 30.000 - 108.000 MHz @ 103.500 MHz ⌂ 256.000 kHz'};
            app.tool_nRowsGrid = uigridlayout(app.toolGrid);
            app.jsBackDoor = uihtml(app.tool_nRowsGrid);
            app.tool_nRows = uilabel(app.tool_nRowsGrid);
            app.tool_nRows.Text = {'0 de 0 varreduras'; '85.4 %'};
            app.tool_nRowsIcon = uiimage(app.tool_nRowsGrid);
            app.tool_nRowsIcon.Enable = 'off';
            app.tool_nRowsIcon.ImageSource = 'LT_Filter2.png';
            app.tool_resetButton = uiimage(app.tool_nRowsGrid);
            app.tool_resetButton.ImageClickedFcn = createCallbackFcn(app, @resetFcn, true);
            app.tool_resetButton.ImageSource = 'LT_Dots.png';
            app.Document = uigridlayout(app.Grid);
            app.axesPanel = uipanel(app.Document);
            app.filter_ContextMenu = uicontextmenu(app.UIFigure);
            app.filter_delButton = uimenu(app.filter_ContextMenu);
            app.filter_delButton.MenuSelectedFcn = createCallbackFcn(app, @filter_delFilter, true);
            app.filter_delButton.Text = 'Excluir';
            app.filter_delAllButton = uimenu(app.filter_ContextMenu);
            app.filter_delAllButton.MenuSelectedFcn = createCallbackFcn(app, @filter_delFilter, true);
            app.filter_delAllButton.Text = 'Excluir todos';
            app.points_ContextMenu = uicontextmenu(app.UIFigure);
            app.points_delButton = uimenu(app.points_ContextMenu);
            app.points_delButton.MenuSelectedFcn = createCallbackFcn(app, @points_delButtonMenuSelected, true);
            app.points_delButton.Text = 'Excluir';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winDriveTest(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end