classdef winRFDataHub < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        mainGrid             matlab.ui.container.GridLayout
        Grid1                matlab.ui.container.GridLayout
        ccTable              ccTools.Table
        HTML                 matlab.ui.control.HTML
        GridLayout           matlab.ui.container.GridLayout
        Slider               matlab.ui.control.Slider
        Spinner              matlab.ui.control.Spinner
        HistogramVisibility  matlab.ui.control.Button
        TableVisibility      matlab.ui.control.Button
        Button               matlab.ui.control.Button
        Button_2             matlab.ui.control.Button
        play_Separator2_4    matlab.ui.control.Image
        LocationLabel        matlab.ui.control.Label
        Location             matlab.ui.control.DropDown
        LocationRefresh      matlab.ui.control.Image
        Panel2               matlab.ui.container.Panel
        Panel1               matlab.ui.container.Panel
    end

    
    properties (Access = private)
        %-----------------------------------------------------------------%
        CallingApp
        RootFolder

        UIAxes1
        UIAxes2
        UIAxes3

        Layout = 3

        Parameters = struct('Family', {}, 'Type', {}, 'Value', {});
        nodeTable        
    end

    
    methods (Access = private)
        %-----------------------------------------------------------------%
        function startup_AxesCreation(app)
            % Geographic axes
            t1 = tiledlayout(app.Panel1, 1, 1, "Padding", "tight");

            app.UIAxes1 = geoaxes(t1, 'FontSize', 6, 'Units', 'pixels', 'Basemap', 'darkwater', 'Box', 'off');
            app.UIAxes1.Layout.Tile = 1;
            addToolbarMapButton(axtoolbar(app.UIAxes1, "default"), "basemap", BasemapNames=["darkwater", "satellite", "streets-light", "streets-dark", "topographic", "streets", "colorterrain", "grayterrain"])
            app.UIAxes1.LatitudeLabel.String  = '';
            app.UIAxes1.LongitudeLabel.String = '';
            hold(app.UIAxes1, 'on')
            try
                geobasemap(app.UIAxes1, 'streets-light')
            catch
            end

            % Regular axes
            t2 = tiledlayout(app.Panel2, 1, 2, "Padding", "tight", "TileSpacing", "tight");

            app.UIAxes2 = uiaxes(t2);
            app.UIAxes2.Layout.Tile = 1;
            xlabel(app.UIAxes2, 'Frequência (MHz)')
            ylabel(app.UIAxes2, 'Registros')
            app.UIAxes2.YScale = 'log';
            app.UIAxes2.YMinorTick = 'on';
            app.UIAxes2.Color = [0.9804 0.9804 0.9804];
            app.UIAxes2.FontSize = 8;
            app.UIAxes2.Interactions = [];

            app.UIAxes3 = uiaxes(t2);
            app.UIAxes3.Layout.Tile = 2;
            xlabel(app.UIAxes3, 'Serviço')
            ylabel(app.UIAxes3, '')
            app.UIAxes3.YScale = 'log';
            app.UIAxes3.YMinorTick = 'on';
            app.UIAxes3.Color = [0.9804 0.9804 0.9804];
            app.UIAxes3.FontSize = 8;
            app.UIAxes3.Interactions = [];

            app.UIAxes1.Interactions = [panInteraction, zoomInteraction, dataTipInteraction];
            enableDefaultInteractivity(app.UIAxes1)

            Interactions = {'datacursor', 'pan', 'zoomin', 'restoreview'};
            plotFcn.axesInteractions(app.UIAxes2, Interactions)
            plotFcn.axesInteractions(app.UIAxes3, Interactions)
            
            drawnow
        end


        %-----------------------------------------------------------------%
        function startup_NodeList(app)
            specData  = app.CallingApp.specData;
            app.nodeTable = table('Size', [0, 3],                                ...
                                  'VariableTypes', {'double', 'double', 'cell'}, ...
                                  'VariableNames', {'lat', 'long', 'location'});

            app.Location.Items = {};
            for ii = 1:numel(specData)
                if specData(ii).GPS.Status
                    msg = sprintf('%.6f, %.6f (%s)', specData(ii).GPS.Latitude,  ...
                                                     specData(ii).GPS.Longitude, ...
                                                     specData(ii).GPS.Location);

                    if isempty(app.Location.Items) | ~contains(app.Location.Items, msg)
                        app.Location.Items{end+1} = msg;
                        app.nodeTable(end+1,:) = {specData(ii).GPS.Latitude, specData(ii).GPS.Longitude, specData(ii).GPS.Location};
                    end
                end
            end

            if isempty(app.Location.Items)
                app.Location.Enable = 0;
            else
                app.Location.Enable = 1;
            end
        end


        %-----------------------------------------------------------------%
        function startup_AnatelDB(app)
            global RFDataHub

            tempTable             = RFDataHub(:,[1, 6:7, 4:5, 12, 13, 29]);
            tempTable.ID          = "#" + string((1:height(RFDataHub))');
            tempTable.Description = "[" + string(RFDataHub.Source) + "] " + string(RFDataHub.Status) + ", " + string(RFDataHub.StationClass) + ", " + string(RFDataHub.Name) + " (Fistel=" + string(RFDataHub.Fistel) + ", Estação=" + string(RFDataHub.Station) + ", M=" + string(RFDataHub.MergeCount) + "), " + string(RFDataHub.Location) + "/" + string(RFDataHub.State);
                        
            if ~isempty(app.Location.Value)
                Node     = regexp(app.Location.Value, '(?<lat>[-]{0,1}\d+[.]{1}\d{6}), (?<lon>[-]{0,1}\d+[.]{1}\d{6})', 'names');
                Node.lat = str2double(Node.lat);
                Node.lon = str2double(Node.lon);

                tempTable.Distance = double(round(fcn.geoDistance_v2(Node, RFDataHub(:, 6:7)), 1));
            else
                tempTable.Distance = -1*ones(height(RFDataHub), 1);
            end

            app.ccTable.Data = tempTable(:,[9, 1:3, 10, 4:8, 11]);
            drawnow

            plot_General(app)
            plot_SelectedRow(app)
            plot_Nodes(app)
            drawnow
        end
        
        
        %-----------------------------------------------------------------%
        function plot_General(app)
            hTable = app.ccTable.Data(app.ccTable.FilteredIndex,:);

            % app.UIAxes1: Geographic plot
            cla(app.UIAxes1)
            hold(app.UIAxes1, "on")

            p1 = geoscatter(app.UIAxes1, hTable.Latitude, hTable.Longitude, "MarkerEdgeColor", "#009578");
            geolimits(app.UIAxes1, 'auto')

            hold(app.UIAxes1, "off")

            % app.UIAxes2 & app.UIAxes3
            p2 = histogram(app.UIAxes2, hTable.Frequency);
            p3 = histogram(app.UIAxes3, categorical(hTable.Service));
            
            tic; t = toc;
            while t < 3
                try
                    app.UIAxes2.Children.EdgeColor = 'none';
                    app.UIAxes2.Children.FaceColor = "#009578";

                    app.UIAxes3.Children.EdgeColor = 'none';
                    app.UIAxes3.Children.FaceColor = "#009578";

                    break
                catch
                    t = toc;
                end
            end

            % DataTip
            if height(hTable)
                plot_DataTipTemplate(app, 'Geographic', p1, hTable)
                plot_DataTipTemplate(app, 'Histogram1', p2, [])
                plot_DataTipTemplate(app, 'Histogram2', p3, [])
            end
        end


        %-----------------------------------------------------------------%
        function plot_SelectedRow(app)
            hSelected = findobj(app.UIAxes1, 'Tag', 'SelectedRow');
            
            idx = app.ccTable.Selection;
            if idx
                hold(app.UIAxes1, 'on')

                if isempty(hSelected)
                    hSelected = geoscatter(app.UIAxes1, app.ccTable.Data{idx,3}, app.ccTable.Data{idx,4}, 'MarkerEdgeColor', '#A2142F', ...
                                                                                                          'MarkerFaceColor', '#A2142F', ...
                                                                                                          'Tag', 'SelectedRow');
                else
                    set(hSelected, 'LatitudeData',  app.ccTable.Data{idx,3}, ...
                                   'LongitudeData', app.ccTable.Data{idx,4})
                end
                plot_DataTipTemplate(app, 'Geographic', hSelected, app.ccTable.Data(idx,:))

                if strcmp(app.ccTable.Data{idx,10}, '-1')
                    app.Button.Enable = 0;
                else
                    app.Button.Enable = 1;
                end

                hold(app.UIAxes1, 'off')
            else
                delete(hSelected)
            end
        end


        %-----------------------------------------------------------------%
        function plot_Nodes(app)
            if ~isempty(app.nodeTable)
                hold(app.UIAxes1, 'on')

                if height(app.nodeTable) > 1
                    p1 = geoscatter(app.UIAxes1, app.nodeTable.lat, app.nodeTable.long, 'Marker', '^', 'MarkerEdgeColor', '#A2142F', 'Tag', 'OthersNodes');
                    plot_DataTipTemplate(app, 'SelectedNode', p1, [])
                end

                idx = find(strcmp(app.Location.Items, app.Location.Value), 1);
                p2 = geoscatter(app.UIAxes1, app.nodeTable.lat(idx), app.nodeTable.long(idx), 'Marker', '^', 'MarkerEdgeColor', '#A2142F', 'MarkerFaceColor', '#A2142F', 'Tag', 'SelectedNode');
                plot_DataTipTemplate(app, 'SelectedNode', p2, [])

                hold(app.UIAxes1, 'off')
            end
        end


        %-----------------------------------------------------------------%
        function plot_DataTipTemplate(app, pType, pHandle, hTable)
            set(pHandle.DataTipTemplate, FontName='Calibri', FontSize=10)

            switch pType
                case 'Geographic'
                    pHandle.DataTipTemplate.DataTipRows(1) = dataTipTextRow('', hTable.Frequency, '%.3f MHz');
                    pHandle.DataTipTemplate.DataTipRows(2) = dataTipTextRow('', hTable.Distance,  '%.0f km');

                    ROWS = height(hTable);
                    if ROWS == 1
                        pHandle.DataTipTemplate.DataTipRows(3) = dataTipTextRow('ID:', {hTable{:,1}});               
                        pHandle.DataTipTemplate.DataTipRows(4) = dataTipTextRow('',    {hTable{:,5}});

                    elseif ROWS > 1
                        pHandle.DataTipTemplate.DataTipRows(3) = dataTipTextRow('ID:', hTable{:,1});
                        pHandle.DataTipTemplate.DataTipRows(4) = dataTipTextRow('',    hTable{:,5});
                    end

                    pHandle.DataTipTemplate.DataTipRows = pHandle.DataTipTemplate.DataTipRows([3,1,4,2]);

                case 'SelectedNode'
                    pHandle.DataTipTemplate.DataTipRows(1).Label = 'Lat:';
                    pHandle.DataTipTemplate.DataTipRows(2).Label = 'Lon:';

                case 'Histogram1'
                    pHandle.DataTipTemplate.DataTipRows = flip(pHandle.DataTipTemplate.DataTipRows);
                    
                    pHandle.DataTipTemplate.DataTipRows(1).Label = 'Banda (MHz):';
                    pHandle.DataTipTemplate.DataTipRows(2).Label = 'Registros:';

                case 'Histogram2'
                    pHandle.DataTipTemplate.DataTipRows = flip(pHandle.DataTipTemplate.DataTipRows);

                    pHandle.DataTipTemplate.DataTipRows(1).Label = 'Serviço:';
                    pHandle.DataTipTemplate.DataTipRows(2).Label = 'Registros:';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            
            app.Grid1.ColumnWidth{2} = 0;
            app.Grid1.RowHeight{4}   = 0;
            
            app.CallingApp = mainapp;
            app.RootFolder = app.CallingApp.RootFolder;
            % fcn.winPosition(app)
            app.UIFigure.WindowState = "maximized";

            d = uiprogressdlg(app.UIFigure, "Message", '<font style="font-size:12;">Em andamento...</font>', "Interpreter", "html", "Indeterminate", "on");
            
            startup_AxesCreation(app)
            startup_NodeList(app)
            startup_AnatelDB(app)

            delete(d)

        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            app.CallingApp.auxiliarWin8 = [];
            delete(app)
            
        end

        % Value changed function: Location
        function LocationValueChanged(app, event)
            
            d = uiprogressdlg(app.UIFigure, "Message", '<font style="font-size:12;">Em andamento...</font>', "Interpreter", "html", "Indeterminate", "on");
            
            startup_AnatelDB(app)

            delete(d)

        end

        % Image clicked function: LocationRefresh
        function LocationRefreshClicked(app, event)
            
            d = uiprogressdlg(app.UIFigure, "Message", '<font style="font-size:12;">Em andamento...</font>', "Interpreter", "html", "Indeterminate", "on");

            InitialValue = app.Location.Value;
            startup_NodeList(app)

            if ~isempty(InitialValue)
                idx = find(strcmp(app.Location.Items, InitialValue), 1);
                if ~isempty(idx)
                    app.Location.Value = InitialValue;
                    LocationValueChanged(app)
                else
                    LocationValueChanged(app)
                end

            else
                if ~isempty(app.Location.Value)
                    LocationValueChanged(app)
                end
            end

            delete(d)
            
        end

        % Button pushed function: HistogramVisibility, TableVisibility
        function LayoutPlotChanged(app, event)

            switch event.Source
                case app.HistogramVisibility
                    if app.Grid1.RowHeight{4}
                        app.HistogramVisibility.Icon = 'DriveTest_Scatter_OFF.png';
                    else
                        app.HistogramVisibility.Icon = 'DriveTest_Scatter_ON.png';
                    end

                case app.TableVisibility
                    if app.Grid1.RowHeight{2}
                        app.TableVisibility.Icon = 'DriveTest_Route_OFF.png';
                    else
                        app.TableVisibility.Icon = 'DriveTest_Route_ON.png';
                    end
            end

            app.Layout = [];
            if contains(app.HistogramVisibility.Icon, 'DriveTest_Scatter_ON.png')
                app.Layout = [app.Layout, 3];
            end

            if contains(app.TableVisibility.Icon, 'DriveTest_Route_ON.png')
                app.Layout = [app.Layout, 1];
            end

            LayoutRatioChanged(app)
            
        end

        % Value changed function: Spinner
        function LayoutRatioChanged(app, event)
            
            value = app.Spinner.Value;

            RowHeight = {0,'1x',0};
            if value >= 0
                RowHeight(app.Layout) = {sprintf('%.1fx', 1-value)};
            else
                if ~isempty(app.Layout)
                    RowHeight(2)          = {sprintf('%.1fx', 1+value)};
                    RowHeight(app.Layout) = {'1x'};
                end
            end
            app.Grid1.RowHeight(2:4) = RowHeight;

        end

        % Callback function: ccTable
        function ccTableSelectionChanged(app, event)

            plot_SelectedRow(app)
            drawnow
            
        end

        % Callback function: ccTable
        function ccTableDataFiltered(app, event)
            
            plot_General(app)
            plot_SelectedRow(app)
            plot_Nodes(app)
            drawnow

        end

        % Button pushed function: Button_2
        function Export_saveImageClicked(app, event)

            Selection = uiconfirm(app.UIFigure, '<font style="font-size:12;">O processo de exportar em planilha Excel a ETL RFDataHub, na sua versão customizada para o appAnalise, poderá demorar alguns minutos. Deseja continuar?</font>', ...
                                                '', 'Interpreter', 'html', 'Options', {'OK', 'Cancelar'}, 'DefaultOption', 2, 'CancelOption', 2);
            if strcmp(Selection, 'Cancelar')
                return
            end
                
            global RFDataHub_info

            d = uiprogressdlg(app.UIFigure, "Message", '<font style="font-size:12;">Em andamento...</font>', "Interpreter", "html", "Indeterminate", "on");

            userPath = app.CallingApp.menu_userPath.Value;
            defaultFileName = [class.Constants.DefaultFileName(userPath, 'RFDataHub', -1) '.xlsx'];

            try
                if ~isempty(app.Location.Value)
                    locName = app.Location.Value;
                else
                    locName = '(none)';
                end

                metaFile = sprintf(['Filename: "%s"\n\n',                   ...
                                    'RFDataHub: %s\n'                       ...
                                    'ColumnRawNames: {%s}\n'                ...
                                    'ColumnName: {%s}\n'                    ...
                                    'ColumnPrecision: {%s}\n'               ...
                                    'RawDataSize: %d rows x %d columns\n\n' ...
                                    'ReferenceLocation: %s\n'               ...
                                    'FilterSentence: %s\n'                  ...
                                    'FilteredDataSize: %d rows'], defaultFileName,                            ...
                                                                  jsonencode(RFDataHub_info),                 ...
                                                                  strjoin(app.ccTable.Data.Properties.VariableNames, ', '), ...
                                                                  strjoin(app.ccTable.ColumnName, ', '),      ...
                                                                  strjoin(app.ccTable.ColumnPrecision, ', '), ...
                                                                  height(app.ccTable.Data),                   ...
                                                                  width(app.ccTable.Data),                    ...
                                                                  locName,                                    ...
                                                                  struct(app.ccTable).Filters.Text,           ...
                                                                  height(app.ccTable.FilteredIndex));

                writetable(app.ccTable.Data(app.ccTable.FilteredIndex,:), defaultFileName, 'WriteMode', 'overwritesheet')
                writecell({metaFile}, replace(defaultFileName, '.xlsx', '.cfg'), "FileType", "text", "QuoteStrings", "none", "WriteMode", "overwrite")
                pause(.100)
    
                defaultFileName = replace(defaultFileName, '\', '\\');
                msg = sprintf('Foram salvos os seguintes arquivos na pasta de trabalho:\n• %s\n• %s', defaultFileName, replace(defaultFileName, '.xlsx', '.cfg'));
                layoutFcn.modalWindow(app.UIFigure, 'ccTools.MessageBox', msg, 'winWidth', '540px');

            catch ME
                fprintf('%s\n', jsonencode(ME))
                layoutFcn.modalWindow(app.UIFigure, 'ccTools.MessageBox', getReport(ME));
            end

            delete(d)

        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            
            d = uiprogressdlg(app.UIFigure, "Message", '<font style="font-size:12;">Em andamento...</font>', "Interpreter", "html", "Indeterminate", "on");

            try
                idx = app.ccTable.Selection;    
                fileName = sprintf('Relatório do Canal - Estação %d.pdf', app.ccTable.Data.Station(idx));

                if ~isfile(fileName)
                    websave(fileName, app.ccTable.Data{idx,10});
                end
                app.HTML.HTMLSource = fileName;

                if isequal(app.Grid1.ColumnWidth{2}, 0) || isequal(app.Grid1.ColumnWidth{2}, '0x')
                    app.Slider.Value = 0;
                    SliderValueChanging(app, struct('Value', 0))
                end

            catch ME
                layoutFcn.modalWindow(app.UIFigure, 'ccTools.MessageBox', getReport(ME));
            end

            delete(d)

        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            
            changingValue = event.Value;
            app.Grid1.ColumnWidth(1:2) = {sprintf('%.1fx', 1+changingValue), sprintf('%.1fx', 1-changingValue)};
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appAnalise: RFDataHub';
            app.UIFigure.Icon = 'LR_icon.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.mainGrid = uigridlayout(app.UIFigure);
            app.Grid1 = uigridlayout(app.mainGrid);
            app.Panel1 = uipanel(app.Grid1);
            app.Panel2 = uipanel(app.Grid1);
            app.GridLayout = uigridlayout(app.Grid1);
            app.LocationRefresh = uiimage(app.GridLayout);
            app.LocationRefresh.ImageClickedFcn = createCallbackFcn(app, @LocationRefreshClicked, true);
            app.LocationRefresh.ImageSource = 'LT_refresh.png';
            app.Location = uidropdown(app.GridLayout);
            app.Location.Items = {};
            app.Location.ValueChangedFcn = createCallbackFcn(app, @LocationValueChanged, true);
            app.Location.Enable = 'off';
            app.Location.Value = {};
            app.LocationLabel = uilabel(app.GridLayout);
            app.LocationLabel.Text = 'Local monitoração:';
            app.play_Separator2_4 = uiimage(app.GridLayout);
            app.play_Separator2_4.Enable = 'off';
            app.play_Separator2_4.ImageSource = 'LT_LineV.png';
            app.Button_2 = uibutton(app.GridLayout, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Export_saveImageClicked, true);
            app.Button_2.Icon = 'LT_save.png';
            app.Button_2.Text = '';
            app.Button = uibutton(app.GridLayout, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'Publish_PDF_16-69bd9a64885e6016ee3e1ed74cc2597d.png';
            app.Button.Enable = 'off';
            app.Button.Text = '';
            app.TableVisibility = uibutton(app.GridLayout, 'push');
            app.TableVisibility.ButtonPushedFcn = createCallbackFcn(app, @LayoutPlotChanged, true);
            app.TableVisibility.Icon = 'DriveTest_Route_ON.png';
            app.TableVisibility.Text = '';
            app.HistogramVisibility = uibutton(app.GridLayout, 'push');
            app.HistogramVisibility.ButtonPushedFcn = createCallbackFcn(app, @LayoutPlotChanged, true);
            app.HistogramVisibility.Icon = 'DriveTest_Scatter_OFF.png';
            app.HistogramVisibility.Text = '';
            app.Spinner = uispinner(app.GridLayout);
            app.Spinner.Step = 0.1;
            app.Spinner.Limits = [-1 1];
            app.Spinner.ValueDisplayFormat = '%.1f';
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @LayoutRatioChanged, true);
            app.Spinner.Value = 0.4;
            app.Slider = uislider(app.GridLayout);
            app.Slider.Limits = [0 1];
            app.Slider.MajorTicks = [];
            app.Slider.MajorTickLabels = {''};
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.MinorTicks = [];
            app.Slider.Value = 1;
            app.HTML = uihtml(app.Grid1);
            app.HTML.HTMLSource = 'pdfLogo.html';
            app.ccTable = ccTools.Table(app.Grid1);
            app.ccTable.ColumnName = {'ID', 'Frequência|(MHz)', 'Latitude', 'Longitude', 'Descrição', 'Serviço', 'Estação', 'Classe', 'BW|(kHz)', 'URL', 'Distância|(km)'};
            app.ccTable.ColumnEditable = [0 0 0 0 0 0 0 0 0 0 0];
            app.ccTable.ColumnAlign = {'center', 'right', 'right', 'right', 'left', 'center', 'right', 'center', 'right', 'left', 'right'};
            app.ccTable.ColumnPrecision = {'%s', '%.3f', '%.6f', '%.6f', '%s', '%d', '%d', '%s', '%.3f', '%s', '%.1f'};
            app.ccTable.hFontSize = 10;
            app.ccTable.SelectionChangedFcn = createCallbackFcn(app, @ccTableSelectionChanged, true);
            app.ccTable.DataFilteredFcn = createCallbackFcn(app, @ccTableDataFiltered, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winRFDataHub(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end