classdef winDriveTest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Grid                     matlab.ui.container.GridLayout
        Image2                   matlab.ui.control.Image
        Panel_3                  matlab.ui.container.Panel
        Tab2_Grid                matlab.ui.container.GridLayout
        GridLayout5              matlab.ui.container.GridLayout
        Panel_4                  matlab.ui.container.Panel
        Tab1_Grid_2              matlab.ui.container.GridLayout
        Tab1_Emissions_2         matlab.ui.control.DropDown
        Tab1_GridA_2             matlab.ui.container.GridLayout
        Tab1_EmissionsRefresh_2  matlab.ui.control.Image
        Tab1_EmissionsLabel_2    matlab.ui.control.Label
        Tab1_Info_2              matlab.ui.control.TextArea
        Tab1_InfoLabel_2         matlab.ui.control.Label
        Tab1_TreeLabel_2         matlab.ui.control.Label
        img_Density              matlab.ui.control.Image
        img_Scatter              matlab.ui.control.Image
        img_Route                matlab.ui.control.Image
        GridLayout4              matlab.ui.container.GridLayout
        Column1_4                matlab.ui.control.Label
        Column1_3                matlab.ui.control.Label
        Column1_2                matlab.ui.control.Label
        Column1                  matlab.ui.control.Label
        UITable                  matlab.ui.control.Table
        GridLayout2_3            matlab.ui.container.GridLayout
        Image4_3                 matlab.ui.control.Image
        PontosdemedioLabel       matlab.ui.control.Label
        GridLayout2_2            matlab.ui.container.GridLayout
        Image4_2                 matlab.ui.control.Image
        DataBinningLabel         matlab.ui.control.Label
        GridLayout2              matlab.ui.container.GridLayout
        Image4                   matlab.ui.control.Image
        CamadasLabel             matlab.ui.control.Label
        GridLayout               matlab.ui.container.GridLayout
        AspectosgeraisLabel      matlab.ui.control.Label
        Image                    matlab.ui.control.Image
        Panel_2                  matlab.ui.container.Panel
        Tab1_Grid                matlab.ui.container.GridLayout
        Tab1_Emissions           matlab.ui.control.DropDown
        Tab1_GridA               matlab.ui.container.GridLayout
        Tab1_EmissionsRefresh    matlab.ui.control.Image
        Tab1_EmissionsLabel      matlab.ui.control.Label
        Tab1_Info                matlab.ui.control.TextArea
        Tab1_InfoLabel           matlab.ui.control.Label
        Tab1_TreeLabel           matlab.ui.control.Label
        Tab1_Tree                matlab.ui.container.CheckBoxTree
        UIAxes2_2                matlab.ui.control.UIAxes
        UIAxes2                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        
        CallingApp
        RootFolder
        
        specData
        Index
        
        specTable

        UIAxes1
        
    end
    
    methods (Access = private)

        function Fcn1_AxesCreation(app)

            app.UIAxes1 = geoaxes(app.Grid, 'FontSize', 6, 'Units', 'pixels', 'Basemap', 'darkwater');

            while true
                try
                    addToolbarMapButton(axtoolbar(app.UIAxes1, "default"), "basemap", BasemapNames=["darkwater", "satellite", "streets-light", "streets-dark", "topographic", "streets", "colorterrain", "grayterrain"])

                    app.UIAxes1.Layout.Row    = 1;
                    app.UIAxes1.Layout.Column = 3:4;
                    app.UIAxes1.LatitudeLabel.String  = '';
                    app.UIAxes1.LongitudeLabel.String = '';

                    hold(app.UIAxes1, 'on')

                    break
                catch
                end
            end

            app.UIAxes2.Interactions = dataTipInteraction;            
            enableDefaultInteractivity(app.UIAxes2)

        end
        

        function Fcn2_Panel(app)   

            % EMISSÕES 
            Fcn3_Emissions(app)

            % ARQUIVOS
            if ~isempty(app.Tab1_Tree.Children)
                delete(app.Tab1_Tree.Children)
            end

            for ii = 1:height(app.specData.RelatedFiles)
                uitreenode(app.Tab1_Tree, 'Text', app.specData.RelatedFiles.Name{ii});
            end
            app.Tab1_Tree.CheckedNodes = app.Tab1_Tree.Children(1);
            Tab1_TreeCheckedNodesChanged(app)
        
        end


        function Fcn3_Emissions(app)

            emissionTable = app.specData.Emissions;
            
            app.Tab1_Emissions.Items = {};
            for ii = 1:height(emissionTable)
                app.Tab1_Emissions.Items{end+1} = sprintf('%d: %.3f MHz @ %.3f kHz',       ...
                                                          ii,                              ...
                                                          emissionTable.Frequency(ii), ...
                                                          emissionTable.BW(ii));
            end

        end


        function Fcn4_geoTable(app)

            timestamp = app.specData.Data{1}';

            lat  = [];
            long = [];
            for ii = 1:numel(app.specData.RelatedGPS)
                lat  = [lat;  app.specData.RelatedGPS{ii}.Matrix(2:end,1)];
                long = [long; app.specData.RelatedGPS{ii}.Matrix(2:end,2)];
            end

            idx = find(strcmp(app.Tab1_Emissions.Items, app.Tab1_Emissions.Value), 1);
            FreqCenter = app.specData.Emissions.Frequency(idx) * 1e+6;
            BW         = app.specData.Emissions.BW(idx)        * 1e+3;

            [chPower, chBinBounds] = Misc_chPower(app.specData, [FreqCenter-BW/2, FreqCenter+BW/2]);

            app.specTable = table(timestamp, lat, long, chBinBounds(:,1), chBinBounds(:,2), chPower);

            geolimits(app.UIAxes1, [min(lat)-.01, max(lat)+.01], [min(long)-.01, max(long)+.01])

            geoplot(app.UIAxes1, lat, long, 'Tag', 'img_Route')            
            geoplot(app.UIAxes1, lat+.01, long+.01, 'Tag', 'img_Scatter')
            geoplot(app.UIAxes1, lat+.02, long+.02, 'Tag', 'img_Density')

        end


        function Fcn5_Plot(app)


        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, idx, Tag)
            
            app.CallingApp = mainapp;
            app.RootFolder = app.CallingApp.RootFolder;
            
            mainMonitor = get(0, 'MonitorPositions');
            
            [~, ind] = max(mainMonitor(:,3));
            mainMonitor = mainMonitor(ind,:);
            
            app.UIFigure.Position(1:2) = [mainMonitor(1)+round((mainMonitor(3)-app.UIFigure.Position(3))/2), ...
                                          mainMonitor(2)+round((mainMonitor(4)-app.UIFigure.Position(4)-30)/2)];

            if app.CallingApp.General.layout.theme ~= "cyan"
                app.CallingApp.startup_theme
            end

            app.specData = app.CallingApp.specData(idx);
            app.Index    = idx;

            app.UIFigure.Tag = Tag;

            Fcn1_AxesCreation(app)
            Fcn2_Panel(app)
            Fcn4_geoTable(app)
            ImageClicked(app, struct('Source', app.Image))
            
            drawnow nocallbacks
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            delete(app)
            
        end

        % Callback function: Tab1_Tree
        function Tab1_TreeCheckedNodesChanged(app, event)
            
            checkedNodes = app.Tab1_Tree.CheckedNodes;
            if isempty(checkedNodes)
                app.Tab1_Tree.CheckedNodes = event.PreviousCheckedNodes;
                return
            end

            metaData = '';
            for ii = 1:numel(checkedNodes)
                idx = find(strcmp(app.specData.RelatedFiles.Name, checkedNodes(ii).Text), 1);

                ObservationTime = sprintf('%s - %s', datestr(app.specData.RelatedFiles.BeginTime(idx), 'dd/mm/yyyy HH:MM:SS'), datestr(app.specData.RelatedFiles.EndTime(idx), 'dd/mm/yyyy HH:MM:SS'));

                metaData = sprintf(['%s• %s\n' ...
                                    '  %s\n' ...
                                    '  %.0f varreduras, %.3f seg (tempo de revisita)\n\n'], ...
                                    metaData, ...
                                    app.specData.RelatedFiles.Name{idx}, ...
                                    ObservationTime, ...
                                    app.specData.RelatedFiles.Samples(idx), ...
                                    app.specData.RelatedFiles.RevisitTime(idx));
            end

            app.Tab1_Info.Value = sprintf(['Faixa: %.3f - %.3f MHz\n\n'  ...
                                           'Pontos por traço: %.0f\n'    ...
                                           'Unidade: %s\n'               ...
                                           'Resolução: %.3f kHz\n\n%s'], ...
                                           app.specData.MetaData.FreqStart / 1e+6,  ...
                                           app.specData.MetaData.FreqStop  / 1e+6,  ...
                                           app.specData.MetaData.DataPoints,        ...
                                           app.specData.MetaData.metaString{1},     ...
                                           app.specData.MetaData.Resolution / 1e+3, ...
                                           metaData);

        end

        % Image clicked function: Tab1_EmissionsRefresh
        function Tab1_EmissionsRefreshImageClicked(app, event)
            
            preSelection = extractAfter(app.Tab1_Emissions.Value, ': ');
            
            app.specData = app.CallingApp.specData(app.Index);
            Fcn3_Emissions(app)

            idx = find(contains(app.Tab1_Emissions.Items, preSelection), 1);
            if ~isempty(idx)
                app.Tab1_Emissions.Value = app.Tab1_Emissions.Items(idx);
            else
                Fcn5_Plot(app)
            end

        end

        % Image clicked function: Image2
        function Image2Clicked(app, event)
            
            if app.Grid.ColumnWidth{1} == 300
                app.Image2.ImageSource = 'LT_HideRight.png';
                app.Grid.ColumnWidth{1} = 0;
            else
                app.Image2.ImageSource = 'LT_HideLeft.png';
                app.Grid.ColumnWidth{1} = 300;
            end

        end

        % Image clicked function: img_Density, img_Route, img_Scatter
        function imgPlotClicked(app, event)
            
            switch event.Source
                case app.img_Route
                    h = findall(app.UIAxes1, 'Tag', 'img_Route');
                    if h.Visible
                        h.Visible = 0;
                        app.img_Route.ImageSource = 'DriveTest_Route_OFF.png';
                    else
                        h.Visible = 1;
                        app.img_Route.ImageSource = 'DriveTest_Route_ON.png';
                    end

                case app.img_Scatter
                    h = findall(app.UIAxes1, 'Tag', 'img_Scatter');
                    if h.Visible
                        h.Visible = 0;
                        app.img_Scatter.ImageSource = 'DriveTest_Scatter_OFF.png';
                    else
                        h.Visible = 1;
                        app.img_Scatter.ImageSource = 'DriveTest_Scatter_ON.png';
                    end

                case app.img_Density
                    h = findall(app.UIAxes1, 'Tag', 'img_Density');
                    if h.Visible
                        h.Visible = 0;
                        app.img_Density.ImageSource = 'DriveTest_Density_OFF.png';
                    else
                        h.Visible = 1;
                        app.img_Density.ImageSource = 'DriveTest_Density_ON.png';
                    end
            end

        end

        % Image clicked function: Image, Image4, Image4_2, Image4_3
        function ImageClicked(app, event)
            
            switch event.Source
                case app.Image;    app.Tab2_Grid.RowHeight([2,4,6,8]) = {'1x',0,0,0};
                case app.Image4_3; app.Tab2_Grid.RowHeight([2,4,6,8]) = {0,'1x',0,0};
                case app.Image4_2; app.Tab2_Grid.RowHeight([2,4,6,8]) = {0,0,'1x',0};
                case app.Image4;   app.Tab2_Grid.RowHeight([2,4,6,8]) = {0,0,0,'1x'};
            end

        end

        % Image clicked function: Tab1_EmissionsRefresh_2
        function Tab1_EmissionsRefresh_2ImageClicked(app, event)
            
            app.UIFigure.WindowState = "fullscreen";

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appAnalise';
            app.UIFigure.Icon = 'LR_icon.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.Grid = uigridlayout(app.UIFigure);
            app.UIAxes2 = uiaxes(app.Grid);
            xlabel(app.UIAxes2, 'Amostra')
            ylabel(app.UIAxes2, 'Densidade (dBm/Hz)')
            app.UIAxes2_2 = uiaxes(app.Grid);
            xlabel(app.UIAxes2_2, 'Frequência')
            ylabel(app.UIAxes2_2, 'Nível (dBm)')
            app.Panel_3 = uipanel(app.Grid);
            app.Tab2_Grid = uigridlayout(app.Panel_3);
            app.Panel_2 = uipanel(app.Tab2_Grid);
            app.Tab1_Grid = uigridlayout(app.Panel_2);
            app.Tab1_Tree = uitree(app.Tab1_Grid, 'checkbox');
            % Assign Checked Nodes
            app.Tab1_Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @Tab1_TreeCheckedNodesChanged, true);
            app.Tab1_TreeLabel = uilabel(app.Tab1_Grid);
            app.Tab1_TreeLabel.Text = 'Arquivo(s) relacionado(s):';
            app.Tab1_InfoLabel = uilabel(app.Tab1_Grid);
            app.Tab1_InfoLabel.Text = 'Metadados:';
            app.Tab1_Info = uitextarea(app.Tab1_Grid);
            app.Tab1_Info.Editable = 'off';
            app.Tab1_GridA = uigridlayout(app.Tab1_Grid);
            app.Tab1_EmissionsLabel = uilabel(app.Tab1_GridA);
            app.Tab1_EmissionsLabel.Text = 'Emissão:';
            app.Tab1_EmissionsRefresh = uiimage(app.Tab1_GridA);
            app.Tab1_EmissionsRefresh.ImageClickedFcn = createCallbackFcn(app, @Tab1_EmissionsRefreshImageClicked, true);
            app.Tab1_EmissionsRefresh.ImageSource = 'LT_refresh.png';
            app.Tab1_Emissions = uidropdown(app.Tab1_Grid);
            app.Tab1_Emissions.Items = {};
            app.Tab1_Emissions.Value = {};
            app.GridLayout = uigridlayout(app.Tab2_Grid);
            app.GridLayout.Tag = 'COLORLOCKED';
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = 'LT_settings.png';
            app.AspectosgeraisLabel = uilabel(app.GridLayout);
            app.AspectosgeraisLabel.Text = 'Aspectos gerais';
            app.GridLayout2 = uigridlayout(app.Tab2_Grid);
            app.GridLayout2.Tag = 'COLORLOCKED';
            app.CamadasLabel = uilabel(app.GridLayout2);
            app.CamadasLabel.Text = 'Camadas';
            app.Image4 = uiimage(app.GridLayout2);
            app.Image4.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image4.ImageSource = '6616211.png';
            app.GridLayout2_2 = uigridlayout(app.Tab2_Grid);
            app.GridLayout2_2.Tag = 'COLORLOCKED';
            app.DataBinningLabel = uilabel(app.GridLayout2_2);
            app.DataBinningLabel.Text = 'Data Binning';
            app.Image4_2 = uiimage(app.GridLayout2_2);
            app.Image4_2.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image4_2.ImageSource = 'LT_Detection.png';
            app.GridLayout2_3 = uigridlayout(app.Tab2_Grid);
            app.GridLayout2_3.Tag = 'COLORLOCKED';
            app.PontosdemedioLabel = uilabel(app.GridLayout2_3);
            app.PontosdemedioLabel.Text = 'Pontos de medição';
            app.Image4_3 = uiimage(app.GridLayout2_3);
            app.Image4_3.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image4_3.ImageSource = 'Instr_Global.png';
            app.GridLayout4 = uigridlayout(app.Tab2_Grid);
            app.UITable = uitable(app.GridLayout4);
            app.UITable.ColumnName = {''; ''; ''; ''};
            app.UITable.RowName = {};
            app.Column1 = uilabel(app.GridLayout4);
            app.Column1.Text = {'  ID'; '  '};
            app.Column1_2 = uilabel(app.GridLayout4);
            app.Column1_2.Text = {'  Latitude'; '  '};
            app.Column1_3 = uilabel(app.GridLayout4);
            app.Column1_3.Text = {'  Longitude'; '  '};
            app.Column1_4 = uilabel(app.GridLayout4);
            app.Column1_4.Text = {'  Varreduras'; '  '};
            app.GridLayout5 = uigridlayout(app.Tab2_Grid);
            app.img_Route = uiimage(app.GridLayout5);
            app.img_Route.ImageClickedFcn = createCallbackFcn(app, @imgPlotClicked, true);
            app.img_Route.Tooltip = {'Rota'};
            app.img_Route.ImageSource = 'DriveTest_Route_OFF.png';
            app.img_Scatter = uiimage(app.GridLayout5);
            app.img_Scatter.ImageClickedFcn = createCallbackFcn(app, @imgPlotClicked, true);
            app.img_Scatter.Tooltip = {'Distorção'};
            app.img_Scatter.ImageSource = 'DriveTest_Scatter_OFF.png';
            app.img_Density = uiimage(app.GridLayout5);
            app.img_Density.ImageClickedFcn = createCallbackFcn(app, @imgPlotClicked, true);
            app.img_Density.Tooltip = {'Densidade'};
            app.img_Density.ImageSource = 'DriveTest_Density_OFF.png';
            app.Panel_4 = uipanel(app.GridLayout5);
            app.Tab1_Grid_2 = uigridlayout(app.Panel_4);
            app.Tab1_TreeLabel_2 = uilabel(app.Tab1_Grid_2);
            app.Tab1_TreeLabel_2.Text = 'Arquivo(s) relacionado(s):';
            app.Tab1_InfoLabel_2 = uilabel(app.Tab1_Grid_2);
            app.Tab1_InfoLabel_2.Text = 'Metadados:';
            app.Tab1_Info_2 = uitextarea(app.Tab1_Grid_2);
            app.Tab1_Info_2.Editable = 'off';
            app.Tab1_GridA_2 = uigridlayout(app.Tab1_Grid_2);
            app.Tab1_EmissionsLabel_2 = uilabel(app.Tab1_GridA_2);
            app.Tab1_EmissionsLabel_2.Text = 'Emissão:';
            app.Tab1_EmissionsRefresh_2 = uiimage(app.Tab1_GridA_2);
            app.Tab1_EmissionsRefresh_2.ImageClickedFcn = createCallbackFcn(app, @Tab1_EmissionsRefresh_2ImageClicked, true);
            app.Tab1_EmissionsRefresh_2.ImageSource = 'LT_refresh.png';
            app.Tab1_Emissions_2 = uidropdown(app.Tab1_Grid_2);
            app.Tab1_Emissions_2.Items = {};
            app.Tab1_Emissions_2.Value = {};
            app.Image2 = uiimage(app.Grid);
            app.Image2.ImageClickedFcn = createCallbackFcn(app, @Image2Clicked, true);
            app.Image2.ImageSource = 'LT_HideLeft.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winDriveTest(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end