classdef winSettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Grid1             matlab.ui.container.GridLayout
        Image2            matlab.ui.control.Image
        Button_4          matlab.ui.control.Button
        VariablesLabel    matlab.ui.control.Label
        Button_3          matlab.ui.control.Button
        Button_2          matlab.ui.control.Button
        GridLayout        matlab.ui.container.GridLayout
        Image             matlab.ui.control.Image
        InfoLabel         matlab.ui.control.Label
        Info              matlab.ui.control.TextArea
        Button            matlab.ui.control.Button
        VariablesLabel_2  matlab.ui.control.Label
        ButtonGroup       matlab.ui.container.ButtonGroup
        ClearWriteColor   matlab.ui.control.RadioButton
        MaxHoldColor      matlab.ui.control.RadioButton
        MeanColor         matlab.ui.control.RadioButton
        MinHoldColor      matlab.ui.control.RadioButton
        Button1_2         matlab.ui.control.Button
        Button2           matlab.ui.control.Button
        Panel             matlab.ui.container.Panel
        Grid2             matlab.ui.container.GridLayout
        Variables_Label1  matlab.ui.control.Label
        Variables_Label2  matlab.ui.control.Label
        Variables_Label3  matlab.ui.control.Label
        CheckBox7         matlab.ui.control.CheckBox
        CheckBox6         matlab.ui.control.CheckBox
        CheckBox5         matlab.ui.control.CheckBox
        CheckBox4         matlab.ui.control.CheckBox
        CheckBox3         matlab.ui.control.CheckBox
        CheckBox2         matlab.ui.control.CheckBox
        CheckBox1         matlab.ui.control.CheckBox
        Button1           matlab.ui.control.Button
    end

    
    properties (Access = private)
        
        CallingApp
        newVersion
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, RootFolder)
            
            mainMonitor = get(0, 'MonitorPositions');
            
            [~, ind] = max(mainMonitor(:,3));
            mainMonitor = mainMonitor(ind,:);
            
            app.UIFigure.Position(1:2) = [mainMonitor(1)+round((mainMonitor(3)-app.UIFigure.Position(3))/2), ...
                                          mainMonitor(2)+round((mainMonitor(4)-app.UIFigure.Position(4))/2)];

            app.CallingApp = mainapp;
            
            [MatlabVersion, ReleaseDate] = version;
            MatlabRoot                   = matlabroot;
            InstalledProducts            = ver;
            
            MatlabProducts = {};
            for ii = 1:numel(InstalledProducts)
                MatlabProducts = [MatlabProducts; sprintf('%s v. %s', InstalledProducts(ii).Name, InstalledProducts(ii).Version)];
            end
            
            Python = pyenv;
                        
            app.Info.Value = sprintf(['Informações acerca das versões instaladas dos módulos do appAnálise:\n• appAnalise v. %s\n'                           ...
                                      '• fiscaliza v. %s (Integração appAnálise/Fiscaliza)\n' ...
                                      '• Base de dados offline da Agência atualizada no dia %s (anateldb)\n\n' ...
                                      'appAnalise\n• %s\n\n' ...
                                      'Matlab\n• v. %s (Release date: %s)\n• %s\n• %s\n\n' ...
                                      'Python\n• v. %s\n• %s\n• %s\n• %s'],                ...                                      
                                      app.CallingApp.Version.appAnalise, ...
                                      app.CallingApp.Version.fiscaliza,  ...
                                      app.CallingApp.Version.anateldb,   ...
                                      RootFolder,                        ...
                                      MatlabVersion,                     ...
                                      ReleaseDate,                       ...
                                      strjoin(MatlabProducts, ', '),     ...
                                      MatlabRoot,     ...
                                      Python.Version, ...
                                      Python.Home,    ...
                                      Python.Library, ...
                                      Python.Executable);
            
            if isdeployed
                set(app.Grid2.Children, 'Enable', 0)
                app.Button2.Enable = 0;
            end
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            app.CallingApp.auxWin5 = [];
            delete(app)
            
        end

        % Button pushed function: Button2
        function Button2Pushed(app, event)
            
            if app.CheckBox1.Value
                assignin('base', 'metaData', app.CallingApp.metaData)
                openvar('metaData')
            end
            
            if app.CheckBox2.Value
                assignin('base', 'Samples', app.CallingApp.Samples)
                openvar('Samples')
            end
            
            if app.CheckBox3.Value
                assignin('base', 'specData', app.CallingApp.specData)
                openvar('specData')
            end
            
            if app.CheckBox4.Value
                assignin('base', 'traceInfo', app.CallingApp.traceInfo)
                openvar('traceInfo')
            end
            
            if app.CheckBox5.Value
                assignin('base', 'peaksTable', app.CallingApp.peaksTable)
                openvar('peaksTable')
            end
            
            if app.CheckBox6.Value
                assignin('base', 'exceptionList', app.CallingApp.exceptionList)
                openvar('exceptionList')
            end
            
            if app.CheckBox7.Value
                assignin('base', 'reportInfo', app.CallingApp.reportInfo)
                openvar('reportInfo')
            end
            
        end

        % Value changed function: CheckBox1, CheckBox2, CheckBox3, 
        % CheckBox4, CheckBox5, CheckBox6, CheckBox7
        function CheckBox1ValueChanged(app, event)
            
            if app.CheckBox1.Value || app.CheckBox2.Value || app.CheckBox3.Value || app.CheckBox4.Value || ...
               app.CheckBox5.Value || app.CheckBox6.Value || app.CheckBox7.Value
                app.Button2.Enable = 1;
            else
                app.Button2.Enable = 0;
            end
            
        end

        % Button pushed function: Button1_2
        function Button1_2Pushed(app, event)
            
            if     app.MinHoldColor.Value;    ind = 1; objLine = findobj('Type', 'Line', 'Tag', 'MinHold');
            elseif app.MeanColor.Value;       ind = 2; objLine = findobj('Type', 'Line', {'Tag', 'Mediana', '-or', 'Tag', 'Média'});
            elseif app.MaxHoldColor.Value;    ind = 3; objLine = findobj('Type', 'Line', 'Tag', 'MaxHold');
            elseif app.ClearWriteColor.Value; ind = 4; objLine = app.CallingApp.line_ClearWrite;
            end
            
            newColor = uisetcolor(app.CallingApp.cTraces(ind,:));
            if numel(newColor) == 3
                app.CallingApp.cTraces(ind,:) = newColor;
                
                if ~isempty(objLine)
                    set(objLine, 'Color', app.CallingApp.cTraces(ind,:))
                    drawnow nocallbacks
                end
            end
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            
        app.CallingApp.cTraces  = [0.07, 0.07, 0.75; ...                    % color1 (MinHold)
                                   0.39, 0.83, 0.07; ...                    % color2 (Median or Mean)
                                   1.00, 0.07, 0.65; ...                    % color3 (MaxHold)
                                   0.93, 0.69, 0.13];                       % color4 (ClearWrite)
        
        objLine = findobj('Type', 'Line', 'Tag', 'MinHold');
        if ~isempty(objLine)
            set(objLine, 'Color', app.CallingApp.cTraces(1,:))
        end
        
        objLine = findobj('Type', 'Line', {'Tag', 'Mediana', '-or', 'Tag', 'Média'});
        if ~isempty(objLine)
            set(objLine, 'Color', app.CallingApp.cTraces(2,:))
        end
        
        objLine = findobj('Type', 'Line', 'Tag', 'MaxHold');
        if ~isempty(objLine)
            set(objLine, 'Color', app.CallingApp.cTraces(3,:))
        end
        
        set(app.CallingApp.line_ClearWrite, 'Color', app.CallingApp.cTraces(4,:))
        drawnow nocallbacks        
            
        end

        % Button pushed function: Button1
        function Button1Pushed(app, event)
            
            try
                FileVersion = jsondecode(webread('https://anatel365-my.sharepoint.com/:t:/g/personal/eric_anatel_gov_br/EcHtzSpFEgxDu7btjSszcT0BgIeCkyuqJLcXgb8LxAOixA?download=1'));
                app.newVersion = struct('appAnalise', FileVersion.appAnalise.Version, ...
                                        'fiscaliza',  FileVersion.fiscaliza.Version,  ...
                                        'anateldb',   FileVersion.anateldb.ReleaseDate);
                
                app.Info.Value{end+1} = sprintf(['\nInformações acerca das últimas versões dos módulos do appAnálise:\n' ...
                                                 '• appAnalise v. %s (Release date: %s)\n'                           ...
                                                 '• fiscaliza v. %s (Release date: %s)\n'                            ...
                                                 '• anateldb v. %s (Release date: %s)\n'],                           ...
                                                 FileVersion.appAnalise.Version, FileVersion.appAnalise.ReleaseDate, ...
                                                 FileVersion.fiscaliza.Version,  FileVersion.fiscaliza.ReleaseDate,  ...
                                                 FileVersion.anateldb.Version,   FileVersion.anateldb.ReleaseDate);
                
                if isequal(app.CallingApp.Version, app.newVersion)
                    app.Image.Visible = 0;
                    uialert(app.UIFigure, 'Módulos atualizados:\n• appAnalise, fiscaliza, anateldb', 'appAnálise', 'Icon', 'success')
                    
                else
                    str1 = {};
                    str2 = {};
                    if strcmp(app.CallingApp.Version.appAnalise, app.newVersion.appAnalise); str1(end+1) = {'appAnalise'};
                    else;                                                                    str2(end+1) = {'appAnalise'};
                    end                    
                    if strcmp(app.CallingApp.Version.fiscaliza,  app.newVersion.fiscaliza);  str1(end+1) = {'fiscaliza'};
                    else;                                                                    str2(end+1) = {'fiscaliza'}; app.Button_2.Enable = 1;
                    end                    
                    if strcmp(app.CallingApp.Version.anateldb,   app.newVersion.anateldb);   str1(end+1) = {'anateldb'};
                    else;                                                                    str2(end+1) = {'anateldb'};  app.Button_3.Enable = 1;
                    end
                    
                    if ~isempty(str1) && ~isempty(str2); msg = sprintf('Módulo(s) atualizado(s):\n• %s\n\nMódulo(s) desatualizado(s):\n• %s', strjoin(str1, ', '), strjoin(str2, ', '));
                    else;                                msg = sprintf('Módulo(s) desatualizado(s):\n• %s', strjoin(str2, ', '));
                    end                    
                    uialert(app.UIFigure, msg, 'appAnálise', 'Icon', 'warning')
                    
                    set(app.Image, 'Visible', 1, 'Tooltip', strjoin(str2, ', '))
                end
                app.Button1.Enable = 0;
                
            catch ME
                uialert(app.UIFigure, ME.message, 'Icon', 'error')
            end
            
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            
            Python   = pyenv;
            [~, msg] = system(fullfile(Python.Home, 'Scripts', 'pip install fiscaliza -U'));
            
            uialert(app.UIFigure, msg, 'appAnálise', "Icon", 'warning')
            
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)


        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appAnálise';
            app.UIFigure.Icon = 'Bluemine.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.Grid1 = uigridlayout(app.UIFigure);
            app.Button1 = uibutton(app.Grid1, 'push');
            app.Button1.ButtonPushedFcn = createCallbackFcn(app, @Button1Pushed, true);
            app.Button1.Icon = 'refreshWhite.png';
            app.Button1.Text = 'Verificar versões';
            app.Panel = uipanel(app.Grid1);
            app.Grid2 = uigridlayout(app.Panel);
            app.CheckBox1 = uicheckbox(app.Grid2);
            app.CheckBox1.ValueChangedFcn = createCallbackFcn(app, @CheckBox1ValueChanged, true);
            app.CheckBox1.Text = 'metaData';
            app.CheckBox2 = uicheckbox(app.Grid2);
            app.CheckBox2.ValueChangedFcn = createCallbackFcn(app, @CheckBox1ValueChanged, true);
            app.CheckBox2.Text = 'Samples';
            app.CheckBox3 = uicheckbox(app.Grid2);
            app.CheckBox3.ValueChangedFcn = createCallbackFcn(app, @CheckBox1ValueChanged, true);
            app.CheckBox3.Text = 'specData';
            app.CheckBox3.Value = true;
            app.CheckBox4 = uicheckbox(app.Grid2);
            app.CheckBox4.ValueChangedFcn = createCallbackFcn(app, @CheckBox1ValueChanged, true);
            app.CheckBox4.Text = 'traceInfo';
            app.CheckBox5 = uicheckbox(app.Grid2);
            app.CheckBox5.ValueChangedFcn = createCallbackFcn(app, @CheckBox1ValueChanged, true);
            app.CheckBox5.Text = 'peaksTable';
            app.CheckBox6 = uicheckbox(app.Grid2);
            app.CheckBox6.ValueChangedFcn = createCallbackFcn(app, @CheckBox1ValueChanged, true);
            app.CheckBox6.Text = 'exceptionList';
            app.CheckBox7 = uicheckbox(app.Grid2);
            app.CheckBox7.ValueChangedFcn = createCallbackFcn(app, @CheckBox1ValueChanged, true);
            app.CheckBox7.Text = 'reportInfo';
            app.Variables_Label3 = uilabel(app.Grid2);
            app.Variables_Label3.Text = 'Relacionadas ao "Relatório":';
            app.Variables_Label2 = uilabel(app.Grid2);
            app.Variables_Label2.Text = 'Relacionadas ao "Playback":';
            app.Variables_Label1 = uilabel(app.Grid2);
            app.Variables_Label1.Text = 'Relacionadas à leitura dos arquivos:';
            app.Button2 = uibutton(app.Grid1, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @Button2Pushed, true);
            app.Button2.Icon = 'Media_PlayWhite.png';
            app.Button2.Text = 'Explorar dados';
            app.Button1_2 = uibutton(app.Grid1, 'push');
            app.Button1_2.ButtonPushedFcn = createCallbackFcn(app, @Button1_2Pushed, true);
            app.Button1_2.Icon = 'EditWhite.png';
            app.Button1_2.Text = 'Editar cor';
            app.ButtonGroup = uibuttongroup(app.Grid1);
            app.MinHoldColor = uiradiobutton(app.ButtonGroup);
            app.MinHoldColor.Text = 'MinHold';
            app.MeanColor = uiradiobutton(app.ButtonGroup);
            app.MeanColor.Text = {'Tendência central'; '(mediana ou média)'};
            app.MaxHoldColor = uiradiobutton(app.ButtonGroup);
            app.MaxHoldColor.Text = 'MaxHold';
            app.ClearWriteColor = uiradiobutton(app.ButtonGroup);
            app.ClearWriteColor.Text = 'ClearWrite';
            app.ClearWriteColor.Value = true;
            app.VariablesLabel_2 = uilabel(app.Grid1);
            app.VariablesLabel_2.Text = 'Cores dos traços apresentados no "Playback"';
            app.Button = uibutton(app.Grid1, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'refresh.png';
            app.Button.Tooltip = {'Voltar às configurações iniciais de cores'};
            app.Button.Text = '';
            app.Info = uitextarea(app.Grid1);
            app.Info.Value = {'General info...'};
            app.GridLayout = uigridlayout(app.Grid1);
            app.InfoLabel = uilabel(app.GridLayout);
            app.InfoLabel.Text = 'Informações gerais';
            app.Image = uiimage(app.GridLayout);
            app.Image.Visible = 'off';
            app.Image.ImageSource = 'Warn_Yellow.png';
            app.Button_2 = uibutton(app.Grid1, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'Redmine.png';
            app.Button_2.Enable = 'off';
            app.Button_2.Tooltip = {'Atualizar biblioteca Fiscaliza'};
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.Grid1, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Icon = 'Sheet.png';
            app.Button_3.Enable = 'off';
            app.Button_3.Tooltip = {'Atualizar base de dados offline'};
            app.Button_3.Text = '';
            app.VariablesLabel = uilabel(app.Grid1);
            app.VariablesLabel.Text = 'Visualização das principais variáveis do appAnálise';
            app.Button_4 = uibutton(app.Grid1, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Icon = 'python-3521655-2945099.png';
            app.Button_4.Tooltip = {'Editar ambiente Python'};
            app.Button_4.Text = '';
            app.Image2 = uiimage(app.Grid1);
            app.Image2.ImageSource = 'Line.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winSettings(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end